classdef seeVieweR < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        SaveStateButton              matlab.ui.control.Button
        LoadStateButton              matlab.ui.control.Button
        ResetSlidersButton           matlab.ui.control.Button
        VolumeVal                    matlab.ui.control.NumericEditField
        ExportMovieButton            matlab.ui.control.Button
        VolumeSlider                 matlab.ui.control.Slider
        VolumeSliderLabel            matlab.ui.control.Label
        OverlayHighVal               matlab.ui.control.NumericEditField
        OverlayLowVal                matlab.ui.control.NumericEditField
        BaseLowVal                   matlab.ui.control.NumericEditField
        BaseHighVal                  matlab.ui.control.NumericEditField
        PctHighOverlay               matlab.ui.control.Label
        PctLowBase                   matlab.ui.control.Label
        PctLowOverlay                matlab.ui.control.Label
        PctHighEditField_Overlay     matlab.ui.control.NumericEditField
        PctHighEditField_Base        matlab.ui.control.NumericEditField
        PctHighBase                  matlab.ui.control.Label
        PctLowEditField_Overlay      matlab.ui.control.NumericEditField
        PctLowEditField_Base         matlab.ui.control.NumericEditField
        TrimOverlayCheckBox          matlab.ui.control.CheckBox
        TrimBaseCheckBox             matlab.ui.control.CheckBox
        ApplySliceMaskDropDown       matlab.ui.control.DropDown
        ApplySliceMaskDropDownLabel  matlab.ui.control.Label
        Overlay_high                 matlab.ui.control.NumericEditField
        Base_high                    matlab.ui.control.NumericEditField
        Overlay_low                  matlab.ui.control.NumericEditField
        Base_low                     matlab.ui.control.NumericEditField
        ORI3_high                    matlab.ui.control.NumericEditField
        ORI2_high                    matlab.ui.control.NumericEditField
        ORI1_high                    matlab.ui.control.NumericEditField
        ORI3_low                     matlab.ui.control.NumericEditField
        ORI2_low                     matlab.ui.control.NumericEditField
        ORI1_low                     matlab.ui.control.NumericEditField
        ORI3Slider                   matlab.ui.control.RangeSlider
        ORI3SliderLabel              matlab.ui.control.Label
        ORI2Slider                   matlab.ui.control.RangeSlider
        ORI2SliderLabel              matlab.ui.control.Label
        ORI1Slider                   matlab.ui.control.RangeSlider
        ORI1SliderLabel              matlab.ui.control.Label
        OverlayAlphaSpinner          matlab.ui.control.Spinner
        OverlayAlphaSpinnerLabel     matlab.ui.control.Label
        BaseAlphaSpinner             matlab.ui.control.Spinner
        BaseAlphaSpinnerLabel        matlab.ui.control.Label
        OverlayThresholdLabel        matlab.ui.control.Label
        OverlaySlider                matlab.ui.control.RangeSlider
        BaseSlider                   matlab.ui.control.RangeSlider
        RenderingDropDownOverlay     matlab.ui.control.DropDown
        RenderingDropDown_2Label     matlab.ui.control.Label
        BaseThresholdLabel           matlab.ui.control.Label
        ThresholdSliderOverlay       matlab.ui.control.RangeSlider
        ColorbarPanelOverlay         matlab.ui.container.Panel
        FlipColormapDropDownOvelay   matlab.ui.control.DropDown
        FlipMapLabel_2               matlab.ui.control.Label
        ColormapDropDownOverlay      matlab.ui.control.DropDown
        OverlayMapLabel              matlab.ui.control.Label
        LoadOverlayButton            matlab.ui.control.Button
        ContrastSpinner              matlab.ui.control.Spinner
        ContrastSpinnerLabel         matlab.ui.control.Label
        Image                        matlab.ui.control.Image
        ProtectionThresholdSpinner   matlab.ui.control.Spinner
        ProtectionThresholdSpinnerLabel  matlab.ui.control.Label
        ExportImageButton            matlab.ui.control.Button
        ErodeBaseImageCheckBox       matlab.ui.control.CheckBox
        ErosionStrengthSpinner       matlab.ui.control.Spinner
        ErosionStrengthSpinnerLabel  matlab.ui.control.Label
        FlipColormapDropDown         matlab.ui.control.DropDown
        FlipMapLabel                 matlab.ui.control.Label
        DenoisingDropDown            matlab.ui.control.DropDown
        DenoisingDropDownLabel       matlab.ui.control.Label
        LightingDropDown             matlab.ui.control.DropDown
        LightingDropDownLabel        matlab.ui.control.Label
        BackgroundDropDown           matlab.ui.control.DropDown
        BackgroundDropDownLabel      matlab.ui.control.Label
        ApplyBrainMaskCheckBox       matlab.ui.control.CheckBox
        ColorbarPanel                matlab.ui.container.Panel
        RenderingDropDown            matlab.ui.control.DropDown
        RenderingDropDownLabel       matlab.ui.control.Label
        TerritoryDropDown            matlab.ui.control.DropDown
        TerritoryDropDownLabel       matlab.ui.control.Label
        VisualizationPanel           matlab.ui.container.Panel
        ThresholdSlider              matlab.ui.control.RangeSlider
        ColormapDropDown             matlab.ui.control.DropDown
        ColorMapDropDownLabel        matlab.ui.control.Label
        LoadBaseButton               matlab.ui.control.Button
    end

    % Copyright (C) Alex A. Bhogal, 2025, University Medical Center Utrecht,
    % a.bhogal@umcutrecht.nl
    %
    % This program is free software: you can redistribute it and/or modify
    % it under the terms of the GNU General Public License as published by
    % the Free Software Foundation, either version 3 of the License, or
    % (at your option) any later version.
    %
    % This program is distributed in the hope that it will be useful,
    % but WITHOUT ANY WARRANTY; without even the implied warranty of
    % MERCHANTABILITY or FITNESS FOR A PARTICULAR PURPOSE.  See the
    % GNU General Public License for more details.
    %
    % You should have received a copy of the GNU General Public License
    % along with this program.  If not, see <https://www.gnu.org/licenses/>.

    properties (Access = private)
        seeVRPath  = []   % Path to seeVR toolbox
        dataImage  = []   % Stores the loaded volume data
        sourceDataImage = [] % keep the original
        voxelSize  = []   % Stores voxel size information
        viewer     = []   % Stores viewer3d object
        volHandle  = []   % Stores volshow object handle
        path       = []   % Path to data
        atlasImage = []   % full atlas
        masks      = []   % Terriory masks
        WBmask     = []   % Whole brain mask
        vesselSkeleton = []   %Vessel Skeleton
        existMasks = 0    % bolean
        overlayImage = [] % Overlay Image
        sourceOverlayImage = [] % keep the original
        overlay = 0;      % bolean
        hfig       = []   % Viewer Object
        tform      = []   % Transform matrix
        niftiInfoBase = []% Info for base image
        basePath   = []   % Path for base image
        baseHistCounts = []  % 1×N   counts  (base volume)
        baseHistEdges = []   % 1×N+1 bin-edges
        overlayHistCounts= []
        overlayHistEdges= []
        currentBaseFrame   = 1;     % which t-volume is displayed
        nBaseFrames        = 1;     % 1 for 3-D, >1 for 4-D
        overlayInfo = []
    end

    methods (Access = private)

        function updateVisualization(app)
            %% --- Fixed global intensity range
            baseRange = app.BaseSlider.UserData;
            fixedMin = baseRange(1) + app.BaseSlider.Value(1) * (baseRange(2) - baseRange(1));
            fixedMax = baseRange(1) + app.BaseSlider.Value(2) * (baseRange(2) - baseRange(1));

            colormapResolution = 256;

            %% --- Base image normalization
            imageData = app.dataImage;
            normImage = (imageData - fixedMin) / (fixedMax - fixedMin);
            normImage = max(min(normImage, 1), 0);  % clip only

            %% --- Threshold mask (controls visibility)
            thrRange = round(app.ThresholdSlider.Value);
            thrMin = thrRange(1);
            thrMax = thrRange(2);
            thresholdMask = (imageData >= thrMin) & (imageData <= thrMax);

            %% --- Region mask
            if app.existMasks
                switch app.TerritoryDropDown.Value
                    case 'ALL'
                        regionMask = ones(size(imageData));
                    case 'Anterior_L'
                        regionMask = app.masks.Anterior_L;
                    case 'Anterior_R'
                        regionMask = app.masks.Anterior_R;
                    case 'PCA'
                        regionMask = app.masks.PCA;
                    case 'VB'
                        regionMask = app.masks.VB;
                    case 'Ventricles'
                        regionMask = app.masks.Ventricles;
                    otherwise
                        regionMask = ones(size(imageData));
                end
            else
                regionMask = ones(size(imageData));
            end

            %% --- Combine masks
            visibilityMask = thresholdMask & regionMask;
            if app.ApplyBrainMaskCheckBox.Value
                visibilityMask = visibilityMask & app.WBmask;
            end

            %% --- Erosion
            if app.ErodeBaseImageCheckBox.Value
                vesselMask = visibilityMask;
                vesselSkel = app.vesselSkeleton;
                skelProtection = imdilate(vesselSkel, strel('sphere', app.ProtectionThresholdSpinner.Value));
                distMap = bwdist(~vesselMask);
                distNorm = distMap / max(distMap(:));
                erosionLevel = app.ErosionStrengthSpinner.Value;
                erodeMask = distNorm > erosionLevel;
                visibilityMask = (erodeMask | skelProtection) & vesselMask;
            end

            %% Slice sliders
            %% ----------- Slice-masks -------------------------------------------------
            % Build one single geometrical mask from the three range sliders
            xLim = app.ORI1Slider.Value;   yLim = app.ORI2Slider.Value;   zLim = app.ORI3Slider.Value;
            xSize = app.ORI1Slider.UserData;  ySize = app.ORI2Slider.UserData;  zSize = app.ORI3Slider.UserData;
            xIdx  = max(1,min(round([xLim(1) xLim(2)]),xSize));
            yIdx  = max(1,min(round([yLim(1) yLim(2)]),ySize));
            zIdx  = max(1,min(round([zLim(1) zLim(2)]),zSize));

            sliceMask = false(size(app.dataImage));
            sliceMask(yIdx(1):yIdx(2), xIdx(1):xIdx(2), zIdx(1):zIdx(2)) = true;

            % Decide where this mask should be applied -------------------------------
            switch app.ApplySliceMaskDropDown.Value
                case 'Base'
                    sliceMaskBase    = sliceMask;           % only base is clipped
                    sliceMaskOverlay = true(size(sliceMask));
                case 'Overlay'
                    sliceMaskBase    = true(size(sliceMask));
                    sliceMaskOverlay = sliceMask;           % only overlay is clipped
                otherwise  % 'All'
                    sliceMaskBase    = sliceMask;
                    sliceMaskOverlay = sliceMask;
            end

            visibilityMask = visibilityMask & sliceMaskBase;

            %% --- Final masked image (color clipped, but visible where mask == true)
            maskedImage = normImage;
            maskedImage(~visibilityMask) = 0;

            %% --- Lock colormap scaling
            maskedImage(1,1,1) = 0;
            maskedImage(1,1,2) = 1;

            %% --- Colormap and alpha
            colmap = app.getCustomColormap(app.ColormapDropDown.Value);
            if strcmp(app.FlipColormapDropDown.Value,'yes')
                colmap = flip(colmap);
            end
            alphaMap = (linspace(0, 1, colormapResolution).^app.ContrastSpinner.Value) * app.BaseAlphaSpinner.Value;
            alphaMap = min(1, alphaMap);
            alphaMap(1) = 0;


            %% --- Overlay preparation (if exists)
            if app.overlay
                overlayData = app.overlayImage;

                if app.ApplyBrainMaskCheckBox.Value
                    overlayData = overlayData.*app.WBmask;
                end

                % Get true intensity range from stored UserData
                trueMin = app.OverlaySlider.UserData(1);
                trueMax = app.OverlaySlider.UserData(2);

                % Map normalized slider values back to intensity range
                oMin = trueMin + app.OverlaySlider.Value(1) * (trueMax - trueMin);
                oMax = trueMin + app.OverlaySlider.Value(2) * (trueMax - trueMin);
                normOverlay = (overlayData - oMin) / (oMax - oMin);
                normOverlay = max(min(normOverlay,1),0);
                normOverlay(~isfinite(normOverlay)) = NaN;   % restore NaNs after clamp

                % Apply threshold and slice mask to overlay
                thrOverlay = round(app.ThresholdSliderOverlay.Value);
                maskOverlay = (overlayData >= thrOverlay(1)) & (overlayData <= thrOverlay(2)) & sliceMaskOverlay;
                normOverlay(~maskOverlay) = 0;

                %normOverlay(~visibilityMask) = 0; note to self - to
                %visualize delta in vessels, uncomment


                if app.ApplyBrainMaskCheckBox.Value
                    normOverlay(~app.WBmask) = 0;
                end

                normOverlay(1,1,1) = 0;
                normOverlay(1,1,2) = 1;

                colmapOverlay = app.getCustomColormap(app.ColormapDropDownOverlay.Value);
                if strcmp(app.FlipColormapDropDownOvelay.Value,'yes')
                    colmapOverlay = flip(colmapOverlay);
                end

                sliderAlpha = app.OverlayAlphaSpinner.Value;

                % Create default alpha map
                alphaOverlay = linspace(0, 1, colormapResolution) * sliderAlpha;

                % Map zero overlay values to alpha = 0
                overlayDataClipped = min(max(normOverlay, 0), 1);  % just to be safe

                % Find colormap bin that corresponds to 0 intensity
                zeroIdx = round((0 - oMin) / (oMax - oMin) * (colormapResolution - 1)) + 1;
                zeroIdx = max(1, min(colormapResolution, zeroIdx));  % clamp to valid index
                alphaOverlay(zeroIdx) = 0;
            end

            %% --- Reset viewer
            if ~isempty(app.viewer) && isgraphics(app.viewer)
                camPos    = app.viewer.CameraPosition;
                camTarget = app.viewer.CameraTarget;
                camUp     = app.viewer.CameraUpVector;
                camZoom   = app.viewer.CameraZoom;
                delete(app.viewer);
            else
                volSize = size(app.dataImage);
                centerVoxel = [volSize(2), volSize(1), volSize(3)] / 2;
                camTarget = centerVoxel .* [app.voxelSize(2), app.voxelSize(1), app.voxelSize(3)];
                camPos = camTarget + [300 0 0];
                camUp = [0 0 1]; camZoom = 1.5;
            end

            app.viewer = viewer3d(Parent=app.VisualizationPanel);
            app.viewer.CameraPosition = camPos;
            app.viewer.CameraTarget = camTarget;
            app.viewer.CameraUpVector = camUp;
            app.viewer.CameraZoom = camZoom;

            % ------------------------------------------------------------------
            % Background & gradient handling
            % ------------------------------------------------------------------
            switch app.BackgroundDropDown.Value
                case 'Dark'          % keep dark with subtle vignette
                    bgColor   = [0 0 0];
                    gradColor = [0.5 0.5 0.5];     % vignette stays
                case 'Light'         % light grey with vignette
                    bgColor   = [0.90 0.90 0.90];
                    gradColor = [0.50 0.50 0.50];
                case 'Black'         % pure black, NO gradient
                    bgColor   = [0 0 0];
                    gradColor = bgColor;           % same ⇒ no gradient
                case 'White'         % pure white, NO gradient
                    bgColor   = [1 1 1];
                    gradColor = bgColor;
            end

            app.viewer.BackgroundColor = bgColor;
            app.viewer.GradientColor   = gradColor;
            app.viewer.RenderingQuality = 'high';
            app.viewer.ScaleBar = 'off';
            app.viewer.Denoising = app.DenoisingDropDown.Value;
            app.viewer.Lighting = app.LightingDropDown.Value;

            %% --- Render base + optional overlay
            if ~app.overlay
                app.volHandle = volshow(maskedImage, ...
                    Parent=app.viewer, Colormap=colmap, Alphamap=alphaMap, ...
                    Transformation=app.tform, RenderingStyle=app.RenderingDropDown.Value);
            else
                app.volHandle = volshow(maskedImage, ...
                    Parent=app.viewer, Colormap=colmap, Alphamap=alphaMap, ...
                    Transformation=app.tform, RenderingStyle=app.RenderingDropDown.Value, ...
                    OverlayData=normOverlay, OverlayColormap=colmapOverlay, ...
                    OverlayRenderingStyle=app.RenderingDropDownOverlay.Value, ...
                    OverlayAlphamap=alphaOverlay);
            end
            %save for export

            %% --- Render base colorbar
            colormapRGB = uint8(255 * permute(colmap, [1 3 2]));
            cbarImage = repmat(colormapRGB, 1, app.ColorbarPanel.Position(3));
            persistent colorbarAxes
            if isempty(colorbarAxes) || ~isvalid(colorbarAxes)
                colorbarAxes = axes('Parent', app.ColorbarPanel, 'Units', 'normalized', 'Position', [0 0 1 1], 'Visible', 'off');
                colorbarAxes.Interactions = [];
            end
            cla(colorbarAxes); image(colorbarAxes, cbarImage);
            axis(colorbarAxes, 'tight'); set(colorbarAxes, 'Visible', 'off', 'YDir', 'normal');

            %% --- Overlay colorbar
            if app.overlay
                overlayRGB = uint8(255 * permute(colmapOverlay, [1 3 2]));
                overlayBar = repmat(overlayRGB, 1, app.ColorbarPanelOverlay.Position(3));
                persistent overlayAxes
                if isempty(overlayAxes) || ~isvalid(overlayAxes) ...
                        || overlayAxes.Parent ~= app.ColorbarPanelOverlay
                    overlayAxes = axes('Parent', app.ColorbarPanelOverlay, 'Units', 'normalized', 'Position', [0 0 1 1], 'Visible', 'off');
                    overlayAxes.Interactions = [];
                end
                cla(overlayAxes); image(overlayAxes, overlayBar);
                axis(overlayAxes, 'tight'); set(overlayAxes, 'Visible', 'off', 'YDir', 'normal');
            end


            % %% --- Draw histograms
            % barW = 0.85;                 % white-bar length as fraction of bar width
            % nRows = 256;                 % one row per colour

            function drawStaticHist(ax, counts256)
                counts256 = double(counts256);          % <-- convert uint32 → double

                % --- optional dynamic-range compression ---------------------------
                % 1) clip tallest 1 % of bins
                clip       = prctile(counts256,99);
                counts256  = min(counts256,clip);

                % 2) gamma / square-root compression
                counts256  = sqrt(counts256);

                % --- normalise to 0 … 1 ------------------------------------------
                if max(counts256)==0
                    counts256(:) = 0;                   % avoid divide-by-zero
                else
                    counts256 = counts256 ./ max(counts256);
                end

                % --- draw ---------------------------------------------------------
                barW = 0.85;  nRows = numel(counts256);
                cla(ax);  hold(ax,'on');
                for r = 1:nRows
                    if counts256(r)
                        line(ax,[0 counts256(r)*barW],[r r], ...
                            'Color','w','LineWidth',1,'HitTest','off');
                    end
                end
                hold(ax,'off');
            end

            % ---------------- BASE ---------------------------------------------
            persistent hHistBase
            if isempty(hHistBase) || ~isgraphics(hHistBase)
                hHistBase = axes('Parent',app.ColorbarPanel, ...
                    'Units','normalized','Position',[0 0 1 1], ...
                    'Color','none','XColor','none','YColor','none', ...
                    'YDir','normal','HitTest','off');
            end
            if ~isempty(app.baseHistCounts)
                drawStaticHist(hHistBase, app.baseHistCounts);
            end

            % ---------------- OVERLAY  (only if one is loaded) ------------------
            if app.overlay
                persistent hHistOv
                if isempty(hHistOv) || ~isgraphics(hHistOv) || ...
                        hHistOv.Parent ~= app.ColorbarPanelOverlay
                    hHistOv = axes('Parent',app.ColorbarPanelOverlay, ...
                        'Units','normalized','Position',[0 0 1 1], ...
                        'Color','none','XColor','none','YColor','none', ...
                        'YDir','normal','HitTest','off');
                end
                if ~isempty(app.overlayHistCounts)
                    drawStaticHist(hHistOv, app.overlayHistCounts);
                end
            end

        end

        function cmap = getCustomColormap(app, cmapName)
            low_val = round(0.2 * 256);
            high_val = 256 - low_val;  % remaining entries

            switch cmapName
                case 'Spectral'
                    cmap = brewermap(256, 'Spectral');
                case 'RdYlBl'
                    cmap = brewermap(256, 'RdYlBu');
                case 'RdYlGn'
                    cmap = brewermap(256, 'RdYlGn');
                case 'Jet'
                    cmap = jet(256);
                case 'Inferno'
                    cmap = inferno(256);
                case 'Turbo'
                    cmap = turbo(256);
                case 'Devon'
                    cmap = crameri('devon', 256);
                case 'grayC'
                    cmap = crameri('grayC', 256);
                case 'Hawaii'
                    cmap = crameri('hawaii', 256);
                case 'Viridis'
                    cmap = viridis(256);
                case 'Magma'
                    cmap = magma(256);
                case 'Plasma'
                    cmap = plasma(256);
                case 'Lajolla'
                    cmap = crameri('lajolla', 256);
                case 'Buda'
                    cmap = crameri('buda', 256);
                case 'Nuuk'
                    cmap = crameri('nuuk', 256);
                case 'Roma'
                    cmap = crameri('roma', 256);
                case 'DSA'
                    cmap = flipud(gray(256));

                otherwise
                    cmap = parula(256);  % fallback
            end
        end

        %-------------------------------------------------------
        function s = val2slider(app, v, rng)
            % real-value → slider-fraction   (clamped 0…1)
            s = (v - rng(1)) ./ diff(rng);
            s = max(0, min(1, s));
        end
        %-------------------------------------------------------
        function v = slider2val(app, s, rng)
            % slider-fraction → real-value
            v = rng(1) + s .* diff(rng);
        end

        function [counts256, edges] = computeHistogram(~, volume, nBins)
            % Robust histogram helper – works for 3-D or 4-D volumes.
            %
            %  • Ignores NaN / Inf voxels
            %  • If *all* voxels are non-finite, returns zeros rather than erroring.

            if nargin < 3,  nBins = 256;  end
            valid = isfinite(volume);

            if ~any(valid(:))                         % ------- all NaN / Inf
                counts256 = zeros(nBins,1,'uint32');  %   flat histogram
                edges     = linspace(0,1,nBins+1);    %   dummy edges
                return
            end

            lo = min(volume(valid),[],'all');         % scalar min
            hi = max(volume(valid),[],'all');         % scalar max
            if lo == hi                               % constant frame → widen a bit
                lo = lo - 1;  hi = hi + 1;
            end

            edges     = linspace(lo, hi, nBins+1);
            counts256 = histcounts(volume(valid),edges).';   % nBins×1 column
        end

        function UIFigureCloseRequest(app,~,~)
            % ← place any clean-up you want here
            delete(app);              % closes the figure and disposes the app
        end

        % -------------------------------------------------------------------------
        %  Build high-res movie (GIF + MP4) that matches the live seeVieweR panel
        % -------------------------------------------------------------------------
        function makeBaseMovieHiRes(app,~)

            % ── 1. Where to save? ────────────────────────────────────────────────────
            saveDir = uigetdir(pwd,'Choose folder for movie files');
            if isequal(saveDir,0),  return, end          % user pressed Cancel

            saveFile = 'viewer_state_movie.mat';
            stateFile = fullfile(saveDir,saveFile);
            app.saveViewerState(stateFile);

            % ── 2. Options dialogue ──────────────────────────────────────────────────
            dlg = { 'GIF frame delay [s]:', ...
                'MP4 fps:'            , ...
                'Add rotation  (yes/no):', ...
                '# rotation frames:'  , ...
                'Rotation range [deg]:', ...
                'Base file-name (no ext):' };
            def = { '0.10','20','yes','36','360','movie' };
            answ = inputdlg(dlg,'Movie options',1,def);
            if isempty(answ),  return, end

            gifDelay   = str2double(answ{1});
            mp4FPS     = str2double(answ{2});
            doRot      = strcmpi(answ{3},'yes');
            rotFrames  = max(1,round(str2double(answ{4})));
            rotDeg     = str2double(answ{5});
            baseName   = strtrim(answ{6});

            gifFile = fullfile(saveDir,[baseName '.gif']);
            mp4File = fullfile(saveDir,[baseName '.mp4']);

            if exist(gifFile,'file'), delete(gifFile); end
            if exist(mp4File,'file'), delete(mp4File); end

            % ── 3. Writer to MP4 + helper for GIF ────────────────────────────────────
            vObj = VideoWriter(mp4File,'MPEG-4');
            vObj.Quality   = 95;
            vObj.FrameRate = mp4FPS;
            open(vObj);

            firstGif = true;
            pixelScale = 2;                    % upscale captured bitmap

            function writeBoth(rgb)        %#ok<INUSD>
                writeVideo(vObj,rgb);
                [ind,cm] = rgb2ind(rgb,256);
                if firstGif
                    imwrite(ind,cm,gifFile,'gif','LoopCount',inf, ...
                        'DelayTime',gifDelay);
                    firstGif = false;
                else
                    imwrite(ind,cm,gifFile,'gif','WriteMode','append', ...
                        'DelayTime',gifDelay);
                end
            end

            % ── 4. Camera/visual settings we copy from the live viewer ───────────────
            liveV = app.viewer;
            vProp = {'CameraPosition','CameraTarget','CameraUpVector','CameraZoom', ...
                'BackgroundColor','GradientColor','RenderingQuality', ...
                'Denoising','Lighting','ScaleBar'};

            hWait = waitbar(0,'Building movie …','WindowStyle','modal');

            % ── 5. PART A – dynamic volumes (if base is 4-D) ─────────────────────────
            nDyn  = app.nBaseFrames;           % 1 for 3-D, >1 for 4-D

            for f = 1:nDyn
                [vol,cMap,aMap,ovVol,ovCMap,ovAMap] = buildFrame(f); %#ok<ASGLU>
                mFig  = uifigure('Color','k','Visible','on');
                mView = viewer3d('Parent',mFig);
                for p = 1:numel(vProp),  mView.(vProp{p}) = liveV.(vProp{p}); end

                vArgs = {'Parent',mView,'Colormap',cMap,'Alphamap',aMap, ...
                    'Transformation',app.tform, ...
                    'RenderingStyle',app.RenderingDropDown.Value};
                if app.overlay
                    vArgs = [vArgs,{'OverlayData',ovVol,'OverlayColormap',ovCMap, ...
                        'OverlayRenderingStyle', ...
                        app.RenderingDropDownOverlay.Value, ...
                        'OverlayAlphamap',ovAMap}];
                end
                volshow(vol,vArgs{:});  drawnow
                rgb = imresize(getframe(mFig).cdata,pixelScale);
                writeBoth(rgb);
                delete(mFig);
                waitbar(f/(nDyn + doRot*rotFrames),hWait);
            end

            % keep last-frame data for rotation step
            [lastVol,lastCM,lastAM,lastOvVol,lastOvCM,lastOvAM] = ...
                deal(vol,cMap,aMap,ovVol,ovCMap,ovAMap);

            % ── 6. PART B – rotation (optional) ──────────────────────────────────────
            if doRot
                camT  = liveV.CameraTarget;
                camUp = liveV.CameraUpVector./norm(liveV.CameraUpVector);
                cam0  = liveV.CameraPosition;

                theta = linspace(0,deg2rad(rotDeg),rotFrames+1);
                for k = 2:numel(theta)
                    R      = rotAxisAngle(camUp,theta(k));
                    camPos = camT + (R*(cam0-camT)')';

                    mFig  = uifigure('Color','k','Visible','on');
                    mView = viewer3d('Parent',mFig);
                    for p = 1:numel(vProp),  mView.(vProp{p}) = liveV.(vProp{p}); end
                    mView.CameraTarget   = camT;
                    mView.CameraPosition = camPos;

                    vArgs = {'Parent',mView,'Colormap',lastCM,'Alphamap',lastAM, ...
                        'Transformation',app.tform, ...
                        'RenderingStyle',app.RenderingDropDown.Value};
                    if app.overlay
                        vArgs = [vArgs,{'OverlayData',lastOvVol,'OverlayColormap',lastOvCM, ...
                            'OverlayRenderingStyle', ...
                            app.RenderingDropDownOverlay.Value, ...
                            'OverlayAlphamap',lastOvAM}];
                    end
                    volshow(lastVol,vArgs{:}); drawnow
                    rgb = imresize(getframe(mFig).cdata,pixelScale);
                    writeBoth(rgb);
                    delete(mFig);
                    waitbar((nDyn+k-1)/(nDyn+rotFrames),hWait);
                end
            end

            % ── 7. clean-up ──────────────────────────────────────────────────────────
            close(vObj);
            close(hWait);
            uialert(app.UIFigure,'Movie saved (GIF + MP4).','Done');

            % ========================================================================
            %  Helper: build ONE frame exactly as updateVisualization would
            % ========================================================================
            function [vol,cMap,aMap,ovVol,ovCMap,ovAMap] = buildFrame(idx)

                if app.nBaseFrames == 1, idx = 1; end   % 3-D base
                frame = app.sourceDataImage(:,:,:,idx);

                if app.TrimBaseCheckBox.Value
                    frame = clipOutliers(frame, ...
                        app.PctLowEditField_Base.Value, ...
                        app.PctHighEditField_Base.Value);
                end

                rng  = app.BaseSlider.UserData;
                vMin = rng(1)+app.BaseSlider.Value(1)*diff(rng);
                vMax = rng(1)+app.BaseSlider.Value(2)*diff(rng);
                vol  = (frame-vMin)/(vMax-vMin);  vol = max(min(vol,1),0);

                thr   = round(app.ThresholdSlider.Value);
                mBase = frame>=thr(1) & frame<=thr(2);

                if app.existMasks
                    switch app.TerritoryDropDown.Value
                        case 'ALL',  mReg = true(size(frame));
                        otherwise,   mReg = app.masks.(app.TerritoryDropDown.Value);
                    end
                else
                    mReg = true(size(frame));
                end
                mAll = mBase & mReg;
                if app.ApplyBrainMaskCheckBox.Value, mAll = mAll & app.WBmask; end

                % slice mask
                xL=app.ORI1Slider.Value; yL=app.ORI2Slider.Value; zL=app.ORI3Slider.Value;
                xS=app.ORI1Slider.UserData; yS=app.ORI2Slider.UserData; zS=app.ORI3Slider.UserData;
                xI=max(1,min(round(xL),xS)); yI=max(1,min(round(yL),yS)); zI=max(1,min(round(zL),zS));
                mSlice = false(size(frame));
                mSlice(yI(1):yI(2),xI(1):xI(2),zI(1):zI(2)) = true;

                if any(strcmp(app.ApplySliceMaskDropDown.Value,{'Base','All'}))
                    mAll = mAll & mSlice;
                end
                vol(~mAll)=0;  vol(1,1,1)=0; vol(1,1,2)=1;

                % colormap + alpha for base
                cMap = app.getCustomColormap(app.ColormapDropDown.Value);
                if strcmp(app.FlipColormapDropDown.Value,'yes'),  cMap = flip(cMap); end
                aMap = (linspace(0,1,256).^app.ContrastSpinner.Value) ...
                    * app.BaseAlphaSpinner.Value;
                aMap(1)=0;

                % overlay  ------------------------------------------------------------
                ovVol=[]; ovCMap=[]; ovAMap=[];
                if app.overlay
                    ovData = app.overlayImage;
                    if app.ApplyBrainMaskCheckBox.Value, ovData = ovData .* app.WBmask; end

                    oRng = app.OverlaySlider.UserData;
                    oMin = oRng(1)+app.OverlaySlider.Value(1)*diff(oRng);
                    oMax = oRng(1)+app.OverlaySlider.Value(2)*diff(oRng);
                    ovVol = (ovData-oMin)/(oMax-oMin);  ovVol = max(min(ovVol,1),0);

                    thrOv = round(app.ThresholdSliderOverlay.Value);
                    mOv   = ovData>=thrOv(1) & ovData<=thrOv(2);
                    if any(strcmp(app.ApplySliceMaskDropDown.Value,{'Overlay','All'}))
                        mOv = mOv & mSlice;
                    end
                    ovVol(~mOv) = 0;

                    ovCMap = app.getCustomColormap(app.ColormapDropDownOverlay.Value);
                    if strcmp(app.FlipColormapDropDownOvelay.Value,'yes')
                        ovCMap = flip(ovCMap);
                    end
                    ovAMap = linspace(0,1,256)*app.OverlayAlphaSpinner.Value;
                    zIdx   = round((0-oMin)/(oMax-oMin)*255)+1;
                    zIdx   = max(1,min(256,zIdx)); ovAMap(zIdx)=0;
                end
            end

            % ------------------------------------------------------------------------
            % Rodrigues rotation (unit axis + angle → 3×3 R)
            function R = rotAxisAngle(axis,angleRad)
                axis = axis(:)./norm(axis);
                x=axis(1); y=axis(2); z=axis(3);
                K = [ 0 -z  y;   z 0 -x;  -y x 0 ];
                R = eye(3) + sin(angleRad)*K + (1-cos(angleRad))*(K*K);
            end

        end

        function saveViewerState(app,filePath)
            % saveViewerState  Write every visual parameter to a .mat file
            %
            %   app.saveViewerState               – ask user where to save
            %   app.saveViewerState('foo.mat')    – save directly

            % ----- 1. choose destination ------------------------------------
            if nargin<2 || isempty(filePath)
                [f,p] = uiputfile('*.mat','Save viewer state as',...
                    fullfile(pwd,'viewer_state.mat'));
                if isequal(f,0),  return, end          % user cancelled
                filePath = fullfile(p,f);
            end

            % ----- 2. make sure a viewer exists -----------------------------
            if isempty(app.viewer) || ~isgraphics(app.viewer)
                uialert(app.UIFigure, ...
                    'Viewer has not been created yet – load a base image first.', ...
                    'Nothing to save','Icon','warning');
                return
            end
            v = app.viewer;

            % ----- 3. collect parameters ------------------------------------
            s = struct();                       % the output struct

            % camera & global
            s.CameraPosition   = v.CameraPosition;
            s.CameraTarget     = v.CameraTarget;
            s.CameraUpVector   = v.CameraUpVector;
            s.CameraZoom       = v.CameraZoom;
            s.BackgroundColor  = v.BackgroundColor;
            s.GradientColor    = v.GradientColor;
            s.RenderingQuality = v.RenderingQuality;
            s.Denoising        = v.Denoising;
            s.Lighting         = v.Lighting;
            s.ScaleBar         = v.ScaleBar;

            % base controls
            s.BaseSliderValue      = app.BaseSlider.Value;
            s.BaseSliderRange      = app.BaseSlider.UserData;
            s.BaseThreshold        = app.ThresholdSlider.Value;
            s.BaseAlpha            = app.BaseAlphaSpinner.Value;
            s.BaseContrast         = app.ContrastSpinner.Value;
            s.BaseColormap         = app.ColormapDropDown.Value;
            s.BaseColormapFlipped  = app.FlipColormapDropDown.Value;
            s.RenderingStyleBase   = app.RenderingDropDown.Value;
            s.TrimBaseOn           = app.TrimBaseCheckBox.Value;
            s.TrimBaseLowPct       = app.PctLowEditField_Base.Value;
            s.TrimBaseHighPct      = app.PctHighEditField_Base.Value;

            % overlay
            s.HasOverlay = app.overlay;
            if app.overlay
                s.OverlaySliderValue     = app.OverlaySlider.Value;
                s.OverlaySliderRange     = app.OverlaySlider.UserData;
                s.OverlayThreshold       = app.ThresholdSliderOverlay.Value;
                s.OverlayAlpha           = app.OverlayAlphaSpinner.Value;
                s.OverlayColormap        = app.ColormapDropDownOverlay.Value;
                s.OverlayColormapFlipped = app.FlipColormapDropDownOvelay.Value;
                s.RenderingStyleOverlay  = app.RenderingDropDownOverlay.Value;
                s.TrimOverlayOn          = app.TrimOverlayCheckBox.Value;
                s.TrimOverlayLowPct      = app.PctLowEditField_Overlay.Value;
                s.TrimOverlayHighPct     = app.PctHighEditField_Overlay.Value;
            end

            % slice masks
            s.ORI1 = app.ORI1Slider.Value;
            s.ORI2 = app.ORI2Slider.Value;
            s.ORI3 = app.ORI3Slider.Value;
            s.ApplySliceMaskTo = app.ApplySliceMaskDropDown.Value;

            % misc toggles
            s.Territory          = app.TerritoryDropDown.Value;
            s.ApplyBrainMask     = app.ApplyBrainMaskCheckBox.Value;
            s.ErodeBase          = app.ErodeBaseImageCheckBox.Value;
            s.ErosionStrength    = app.ErosionStrengthSpinner.Value;
            s.ProtectionThreshold= app.ProtectionThresholdSpinner.Value;

            % ----- 4. save ---------------------------------------------------
            save(filePath,'-struct','s');
            disp('Viewer state saved');
        end

        function loadViewerState(app,filePath)
            % loadViewerState  Restore every visual parameter from a .mat file
            %
            %   app.loadViewerState              – ask user to pick a .mat
            %   app.loadViewerState('foo.mat')   – load directly
            %
            % If something in the file is incompatible with the currently‑loaded
            % images (different volume dimensions, missing overlay, etc.) the method
            % aborts gracefully and leaves the viewer unchanged.

            % ── 1. choose file ──────────────────────────────────────────────────
            if nargin<2 || isempty(filePath)
                [f,p] = uigetfile('*.mat','Load viewer state');
                if isequal(f,0),  return, end
                filePath = fullfile(p,f);
            end
            if ~isfile(filePath)
                uialert(app.UIFigure,'State file not found.','Load error','Icon','error');
                return
            end
            st = load(filePath);              % <—— may throw if file corrupt

            % ── 2. wrap ALL changes in a try–catch so we can roll back if needed ─
            backup = app;                     % shallow copy handles + props
            try
                % ========== begin risky assignments ============================

                % ---- base controls -------------------------------------------
                safeSet(app.TrimBaseCheckBox,      'Value',  st,'TrimBaseOn');
                safeSet(app.PctLowEditField_Base,  'Value',  st,'TrimBaseLowPct');
                safeSet(app.PctHighEditField_Base, 'Value',  st,'TrimBaseHighPct');

                safeSet(app.BaseSlider,            'Value',  st,'BaseSliderValue');
                safeSet(app.ThresholdSlider,       'Value',  st,'BaseThreshold');
                safeSet(app.BaseAlphaSpinner,      'Value',  st,'BaseAlpha');
                safeSet(app.ContrastSpinner,       'Value',  st,'BaseContrast');
                safeSet(app.ColormapDropDown,      'Value',  st,'BaseColormap');
                safeSet(app.FlipColormapDropDown,  'Value',  st,'BaseColormapFlipped');
                safeSet(app.RenderingDropDown,     'Value',  st,'RenderingStyleBase');

                % ---- overlay controls ----------------------------------------
                if isfield(st,'HasOverlay') && st.HasOverlay && app.overlay
                    safeSet(app.TrimOverlayCheckBox,     'Value', st,'TrimOverlayOn');
                    safeSet(app.PctLowEditField_Overlay, 'Value', st,'TrimOverlayLowPct');
                    safeSet(app.PctHighEditField_Overlay,'Value', st,'TrimOverlayHighPct');

                    safeSet(app.OverlaySlider,           'Value', st,'OverlaySliderValue');
                    safeSet(app.ThresholdSliderOverlay,  'Value', st,'OverlayThreshold');
                    safeSet(app.OverlayAlphaSpinner,     'Value', st,'OverlayAlpha');
                    safeSet(app.ColormapDropDownOverlay, 'Value', st,'OverlayColormap');
                    safeSet(app.FlipColormapDropDownOvelay,'Value',st,'OverlayColormapFlipped');
                    safeSet(app.RenderingDropDownOverlay,'Value', st,'RenderingStyleOverlay');
                end

                % ---- geometry & masks ----------------------------------------
                safeSet(app.ORI1Slider,'Value', st,'ORI1');
                safeSet(app.ORI2Slider,'Value', st,'ORI2');
                safeSet(app.ORI3Slider,'Value', st,'ORI3');
                safeSet(app.ApplySliceMaskDropDown,'Value',st,'ApplySliceMaskTo');

                % ---- refresh viewer ------------------------------------------
                app.updateVisualization();

                % ---- camera & background (needs viewer) ----------------------
                v = app.viewer;    % updateVisualization just created/updated it
                safeSet(v,'CameraPosition',   st,'CameraPosition');
                safeSet(v,'CameraTarget',     st,'CameraTarget');
                safeSet(v,'CameraUpVector',   st,'CameraUpVector');
                safeSet(v,'CameraZoom',       st,'CameraZoom');
                safeSet(v,'BackgroundColor',  st,'BackgroundColor');
                safeSet(v,'GradientColor',    st,'GradientColor');
                safeSet(v,'RenderingQuality', st,'RenderingQuality');
                safeSet(v,'Denoising',        st,'Denoising');
                safeSet(v,'Lighting',         st,'Lighting');
                safeSet(v,'ScaleBar',         st,'ScaleBar');

                % ========== if we get here everything worked ==================
                uialert(app.UIFigure,'Viewer state loaded.','Done');

            catch ME
                % -------- rollback every change -------------------------------
                try  app = backup;  catch,  end   % best‑effort rollback
                uialert(app.UIFigure, ...
                    sprintf('Could not apply viewer state:\n%s',ME.message), ...
                    'Load error','Icon','warning');
            end

            % ── helper: set property only if field exists ─────────────────────
            function safeSet(obj,prop,srcStruct,field)
                if isfield(srcStruct,field)
                    try
                        obj.(prop) = srcStruct.(field);
                    catch
                        % silently ignore invalid assignments (e.g. out of range)
                    end
                end
            end
        end

    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            currentPath = strsplit(path, pathsep);
            hasSeeVR = any(contains(currentPath, [filesep fullfile('seeVR','read_write') filesep], 'IgnoreCase', true));

            if ~hasSeeVR
                % Prompt user to locate the seeVR folder
                msg = ['The required toolbox folder "\seeVR\" is not currently on the MATLAB path.', newline, ...
                    'Please select the folder containing the toolbox (e.g., E:\Users\person\seeVR).'];
                uiwait(warndlg(msg, 'Toolbox Missing', 'modal'));

                chosenFolder = uigetdir('C:\Users\...\seeVR_main', 'Select your seeVR toolbox root folder');

                addpath(genpath(chosenFolder));
                savepath();  % optional: persist across sessions

                currentPath = strsplit(path, pathsep);
                hasSeeVR = any(contains(currentPath, [filesep fullfile('seeVieweR') filesep], 'IgnoreCase', true));
                if hasSeeVR
                    msgbox('seeVR toolbox path added successfully.','Success','modal');
                else
                    errordlg('Incorrect folder selected - seeVR toolbox was not added. Try manually adding before running seeVieweR or restart and try again.','Path Not Set');
                end
            end

            datadir = pwd;

            cd(datadir);

            % Create Colorbar Panel
            viewerPos = app.VisualizationPanel.Position;
            colorbarX = viewerPos(1) + viewerPos(3) + 10;
            colorbarY = viewerPos(2);
            colorbarW = 40;
            colorbarH = viewerPos(4);
            app.ColorbarPanel = uipanel(app.UIFigure);
            app.ColorbarPanel.Position = [colorbarX, colorbarY, colorbarW, colorbarH];
            % fix wrong CloseRequestFcn that Designer wrote accidentally
            app.UIFigure.CloseRequestFcn = @app.UIFigureCloseRequest;

        end

        % Button pushed function: LoadBaseButton
        function LoadBaseButtonPushed(app, event)


            % --- Reset viewer if overlay exists
            if app.overlay && isgraphics(app.viewer)
                delete(app.viewer);     % delete current viewer
                app.viewer = [];        % clear handle
                app.overlay = 0;
                app.ColorbarPanelOverlay.Visible = 'off';
                app.FlipColormapDropDownOvelay.Visible = 'off';
                app.ColormapDropDownOverlay.Visible = "off";
                app.FlipMapLabel_2.Visible = 'off';
                app.OverlayMapLabel.Visible = 'off';
                app.ThresholdSliderOverlay.Visible = 'off';
                app.RenderingDropDownOverlay.Visible = "off";
                app.OverlaySlider.Visible = 'off';
                app.OverlayAlphaSpinner.Visible = 'off';
                app.OverlayAlphaSpinnerLabel.Visible = 'off';
                app.ThresholdSliderOverlay.Visible = "off";
                app.OverlayThresholdLabel.Visible = "off";
                app.Overlay_low.Visible = 'off';
                app.Overlay_high.Visible = 'off';
                app.overlay = 0;
                app.ApplySliceMaskDropDown.Value = 'All';
                app.ApplySliceMaskDropDown.Items = {'All'};
                app.PctLowEditField_Overlay.Visible = "off";
                app.PctHighEditField_Overlay.Visible = "off";
                app.TrimOverlayCheckBox.Visible = "off";
                app.OverlayHighVal.Visible = "off";
                app.OverlayLowVal.Visible = "off";
                app.RenderingDropDownLabel.Visible = "off";
            end

            [file, path] = uigetfile({'*.nii;*.nii.gz'}, 'Select base image');
            if isequal(file,0),  return;  end

            niftiPath          = fullfile(path,file);
            baseInfo           = niftiinfo(niftiPath);
            rawBase            = double(niftiread(baseInfo));        % 3-D or 4-D
            rawBase(rawBase==0)= NaN;                                % keep NaNs

            app.niftiInfoBase  = baseInfo;
            app.voxelSize      = baseInfo.PixelDimensions;
            app.basePath       = niftiPath;

            sx = app.voxelSize(2);  sy = app.voxelSize(1);  sz = app.voxelSize(3);
            app.tform = affinetform3d([sx 0 0 0; 0 sy 0 0; 0 0 sz 0; 0 0 0 1]);

            rescaleBase = false;
            if max(rawBase(:)) > 4e4
                rescaleBase = true;
                disp('Input image has large intensity range. Rescaling to [0, 1000] for stability.');
                rawBase = 1000 * (rawBase - min(rawBase(:))) / ...
                    (max(rawBase(:)) - min(rawBase(:)));
                disp('... possibly anatomic image, setting visualization parameters');
                app.ColormapDropDown.Value = 'grayC';
            end


            % ----- volume handling ----------------------------------------

            if ndims(rawBase)==4 && size(rawBase,4) > 1          % ========== 4-D ==========
                app.nBaseFrames      = size(rawBase,4);
                app.currentBaseFrame = size(rawBase,4);

                app.sourceDataImage  = rawBase;          % keep full series
                app.dataImage        = rawBase(:,:,:,end); % show last frame

                % ----- slider set-up -------------------------------------------------
                app.VolumeSlider.Limits  = [1 app.nBaseFrames];
                app.VolumeSlider.Value   = 1;

                midIdx = ceil(app.nBaseFrames/2);        % first | middle | last ticks
                app.VolumeSlider.MajorTicks      = [1 app.nBaseFrames];
                app.VolumeSlider.MajorTickLabels = { '1', num2str(app.nBaseFrames) };

                app.VolumeSlider.Visible = 'on';
                app.VolumeVal.Visible    = 'on';
                app.VolumeSliderLabel.Visible = "on";
                app.VolumeSlider.Limits = [1 size(rawBase,4)];
                % ----- numeric read-out ---------------------------------------------
                app.VolumeVal.Limits  = [1 app.nBaseFrames];
                app.VolumeVal.Value   = 1;
                app.VolumeVal.Visible = 'on';
            else                                                % ========== 3-D ==========
                app.nBaseFrames      = 1;
                app.currentBaseFrame = 1;

                app.sourceDataImage  = rawBase;
                app.dataImage        = rawBase;

                app.VolumeSlider.Visible = 'off';
                app.VolumeVal.Visible    = 'off';
                app.VolumeSliderLabel.Visible = "off";
            end

            % ---------- initial histogram for the displayed frame ---------------
            [app.baseHistCounts, app.baseHistEdges] = computeHistogram(app, app.dataImage);

            % Binary vessel mask from original data
            vesselBinary = app.dataImage > 0;

            % Generate 3D skeleton - this is assuming input is MRA but can
            % be removed in future versions
            if size(vesselBinary, ndims(vesselBinary)) > 1
                vesselBinary = squeeze(vesselBinary(:,:,:,end));
                app.vesselSkeleton = bwskel(vesselBinary);
            end

            cd(path);
            app.path = path;

            % Setup OverlaySlider (used for visual scaling of overlay colors)
            baseData = app.dataImage(:);
            baseData = baseData(~isnan(baseData) & ~isinf(baseData));  % remove NaN/Inf

            minBase = min(baseData);
            maxBase = max(baseData);
            if minBase == maxBase
                minBase = minBase - 1;
                maxBase = maxBase + 1;
            end

            app.BaseSlider.UserData = [minBase, maxBase];
            app.BaseSlider.Limits = [0, 1];

            if rescaleBase
                rescaledValid = rawBase(~isnan(rawBase) & ~isinf(rawBase));
                nbins = 1000;
                [counts, edges] = histcounts(rescaledValid, nbins);
                % Step 3: Find high-density region using cumulative sum
                cdf = cumsum(counts) / sum(counts);
                % Define adaptive clipping thresholds (e.g., keep 99% of mass)
                lowerIdx = find(cdf >= 0.005, 1, 'first'); % first bin above 0.5%
                upperIdx = find(cdf >= 0.995, 1, 'first'); % first bin above 99.5%
                % Convert bin indices to intensity values
                lowerBound = edges(lowerIdx);
                upperBound = edges(min(upperIdx, length(edges)-1));  % avoid out-of-bounds
                % Set normalized initial values (0–1 range)
                app.BaseSlider.Value = [lowerBound/maxBase upperBound/maxBase];
            else
                % Set the actual min/max for slider (rescaled to 0–1)
                app.BaseSlider.Limits = [0 1];

                % Set initial overlay visualization range (10% to 80%)
                baseMinVal = minBase + 0.1 * (maxBase - minBase);
                baseMaxVal = minBase + 0.8 * (maxBase - minBase);

                % Set normalized initial values (0–1 range)
                app.BaseSlider.Value = [(baseMinVal - minBase) / (maxBase - minBase), ...
                    (baseMaxVal - minBase) / (maxBase - minBase)];
            end

            %% Threshold Slider
            validVox          = isfinite(app.dataImage);        % no NaN / Inf
            trueMin           = double(round(min(app.dataImage(validVox)),2));
            trueMax           = double(round(max(app.dataImage(validVox)),2));

            app.ThresholdSlider.Limits = [trueMin , trueMax];
            app.ThresholdSlider.Value = [trueMin + abs(0.001*trueMin) , trueMax-0.001*trueMax];
            app.ThresholdSlider.Visible = 'on';

            %set value boxes
            app.Base_low.Limits = [trueMin, trueMax-0.001*trueMax];
            app.Base_high.Limits = [trueMin+0.001*trueMin, trueMax];
            app.Base_low.Value = app.ThresholdSlider.Value(1);
            app.Base_high.Value = app.ThresholdSlider.Value(2);

            rng    = app.BaseSlider.UserData;               % [trueMin trueMax]
            vals   = slider2val(app, app.BaseSlider.Value, rng);
            app.BaseLowVal.Value = vals(1);
            app.BaseHighVal.Value = vals(2);

            %% Load arterial atlas mask
            atlasPath = fullfile(path, 'structToMNI', 'Inverse', 'ArterialAtlas_level2_toInput.nii.gz');
            if exist(atlasPath, 'file')
                atlas = niftiread(atlasPath);
                app.atlasImage = double(atlas);

                territoryIndices = struct( ...
                    'Anterior_L', [1, 3], ...
                    'Anterior_R', [2, 4], ...
                    'PCA',        [5, 6], ...
                    'VB',         [7, 8], ...
                    'Ventricles', [9, 10] ...
                    );

                masks = struct();
                for name = fieldnames(territoryIndices)'
                    masks.(name{1}) = ismember(app.atlasImage, territoryIndices.(name{1}));
                end
                app.masks = masks;
                app.TerritoryDropDown.Visible = 'on';
                app.TerritoryDropDownLabel.Visible = 'on';
                app.existMasks = 1;
                % set slice mask options
            end

            %% Load brain mask
            brainMaskFile = ls(fullfile(path, '*_brain_mask.nii*'));
            try
                if exist(fullfile(path, brainMaskFile), 'file')
                    WBmask = niftiread(fullfile(path, brainMaskFile));
                    app.WBmask = double(WBmask);
                    app.ApplyBrainMaskCheckBox.Visible = 'on';
                end
            catch
                disp('Could not load brain mask...')
            end

            %% Only initialize viewer once
            if isempty(app.viewer) %|| ~isvalid(app.viewer)
                app.viewer.CameraZoom = 1.5;
                app.viewer.GradientColor = [0.5 0.5 0.5];
                app.viewer.BackgroundColor = [0 0 0];
                app.viewer.RenderingQuality = 'high';
                app.viewer.ScaleBar = 'off';
                app.viewer.Denoising = app.DenoisingDropDown.Value;
                app.viewer.Lighting = app.LightingDropDown.Value;
            end

            %% Slice Slider

            volSize = size(app.dataImage);

            % Set slider limits to normalized range [0, 1]
            app.ORI1Slider.Limits = [1 volSize(2)];
            app.ORI2Slider.Limits = [1 volSize(1)];
            app.ORI3Slider.Limits = [1 volSize(3)];

            % Set full range (to initially show entire volume)
            app.ORI1Slider.Value = [1 volSize(2)];
            app.ORI2Slider.Value = [1 volSize(1)];
            app.ORI3Slider.Value = [1 volSize(3)];

            % Store volume size for later slicing use
            app.ORI1Slider.UserData = volSize(2);  % X = columns
            app.ORI2Slider.UserData = volSize(1);  % Y = rows
            app.ORI3Slider.UserData = volSize(3);  % Z = slices

            app.ORI1_low.Limits = [1 volSize(2)-1];
            app.ORI2_low.Limits = [1 volSize(1)-1];
            app.ORI3_low.Limits = [1 volSize(3)-1];

            app.ORI1_high.Limits = [2 volSize(2)];
            app.ORI2_high.Limits = [2 volSize(1)];
            app.ORI3_high.Limits = [2 volSize(3)];

            app.ORI1_low.Value = 1; app.ORI1_high.Value = volSize(2);
            app.ORI2_low.Value = 1; app.ORI2_high.Value = volSize(1);
            app.ORI3_low.Value = 1; app.ORI3_high.Value = volSize(3);

            %% UI Visibility
            app.BaseSlider.Visible = "on";
            app.BaseSlider.Position(2) = app.VisualizationPanel.Position(2);
            app.BaseSlider.Position(4) = app.VisualizationPanel.Position(4);
            app.ColorbarPanel.Visible = 'on';
            app.ThresholdSlider.Visible = 'on';
            app.BaseThresholdLabel.Visible = 'on';
            app.Base_low.Visible = "on";
            app.Base_high.Visible = "on";
            app.ExportImageButton.Visible = 'on';
            app.LoadOverlayButton.Visible = 'on';
            app.ORI1Slider.Visible = 'on';
            app.ORI1SliderLabel.Visible = "on";
            app.ORI2Slider.Visible = 'on';
            app.ORI2SliderLabel.Visible = "on";
            app.ORI3Slider.Visible = 'on';
            app.ORI3SliderLabel.Visible = "on";
            app.BaseAlphaSpinner.Visible = 'on';
            app.BaseAlphaSpinnerLabel.Visible = 'on';
            app.TrimBaseCheckBox.Visible = "on";
            app.BaseHighVal.Visible = "on";
            app.BaseLowVal.Visible = "on";
            app.LoadStateButton.Visible = "on";
            app.SaveStateButton.Visible = "on";
            app.ExportImageButton.Visible = "on";
            app.ExportMovieButton.Visible = "on";
            %% Refresh Visualization
            app.updateVisualization();
        end

        % Button pushed function: LoadOverlayButton
        function LoadOverlayButtonPushed(app, event)
            app.overlayImage = [];
            app.sourceOverlayImage = [];
            [file,path] = uigetfile('*.nii;*.nii.gz');
            if isequal(file,0)
                return;
            end

            baseInfo = app.niftiInfoBase;
            baseVol  = app.dataImage;
            movInfo  = niftiinfo(fullfile(path,file)); app.overlayInfo = movInfo;
            movVol   = niftiread(movInfo);
            % can change interpolation: cubic, spline, linear, nearest
            % neighbor
            aligned = overlay_to_base(baseVol, baseInfo, movVol, movInfo, 'linear');
            aligned(aligned == 0) = NaN;

            % If dynamic range is too large, rescale image
            if max(aligned(:)) > 1e5
                disp('Input image has large intensity range. Rescaling to [0, 1000] for stability.');
                aligned = 1000 * (aligned - min(aligned(:))) / (max(aligned(:)) - min(aligned(:)));
                disp('... possibly anatomic image, setting visualization parameters')
                app.ColormapDropDownOverlay.Value = 'grayC';
                rescaleOverlay = 1;
            else
                rescaleOverlay = 0;
            end

            app.overlayImage = aligned;
            app.sourceOverlayImage = app.overlayImage; %save reference overlay

            % -------------- Calculate Histogram -----------------
            [app.overlayHistCounts, app.overlayHistEdges] = computeHistogram(app, app.overlayImage);

            try
                app.FlipColormapDropDownOvelay.Visible = 'on';
                app.ColormapDropDownOverlay.Visible = "on";
                app.FlipMapLabel_2.Visible = 'on';
                app.OverlayMapLabel.Visible = 'on';
                app.overlay = 1;
                app.RenderingDropDownOverlay.Visible = "on";
                app.RenderingDropDown_2Label.Visible = "on";

                % Setup OverlaySlider (used for visual scaling of overlay colors)
                overlayData = app.overlayImage(:);
                overlayData = overlayData(~isnan(overlayData) & ~isinf(overlayData));  % remove NaN/Inf

                validVox          = isfinite(overlayData);        % no NaN / Inf
                minBase           = round(min(overlayData(validVox)),2);
                maxBase           = round(max(overlayData(validVox)),2);

                clear overlayData;

                % Store the true min/max for normalization later
                app.OverlaySlider.UserData = [minBase, maxBase];  % store for later use
                % Set the actual min/max for slider (rescaled to 0–1)
                app.OverlaySlider.Limits = [0 1];

                if rescaleOverlay
                    rescaledValid = app.overlayImage(~isnan(app.overlayImage) & ~isinf(app.overlayImage));
                    nbins = 1000;
                    [counts, edges] = histcounts(rescaledValid, nbins);
                    % Step 3: Find high-density region using cumulative sum
                    cdf = cumsum(counts) / sum(counts);
                    % Define adaptive clipping thresholds (e.g., keep 99% of mass)
                    lowerIdx = find(cdf >= 0.005, 1, 'first'); % first bin above 0.5%
                    upperIdx = find(cdf >= 0.995, 1, 'first'); % first bin above 99.5%
                    % Convert bin indices to intensity values
                    lowerBound = edges(lowerIdx);
                    upperBound = edges(min(upperIdx, length(edges)-1));  % avoid out-of-bounds
                    % Set normalized initial values (0–1 range)
                    app.OverlaySlider.Value = double([lowerBound/maxBase upperBound/maxBase]);
                else

                    % Set the actual min/max for slider (rescaled to 0–1)
                    app.OverlaySlider.Limits = [0 1];

                    % Set initial overlay visualization range (10% to 80%)
                    overlayMinVal = minBase + 0.1 * (maxBase - minBase);
                    overlayMaxVal = minBase + 0.8 * (maxBase - minBase);

                    % Set normalized initial values (0–1 range)
                    app.OverlaySlider.Value = [(overlayMinVal - minBase) / (maxBase - minBase), ...
                        (overlayMaxVal - minBase) / (maxBase - minBase)];
                end

                % Make the slider visible
                app.OverlaySlider.Visible = 'on';

                %Setup threshold slider
                minLimit = double(round(min(app.overlayImage(:)),2));
                maxLimit = double(round(max(app.overlayImage(:)),2));
                app.ThresholdSliderOverlay.Limits = [minLimit, maxLimit];

                % Set symmetric value range around zero if bipolar

                if min(app.overlayImage(:)) < 0 && max(app.overlayImage(:)) > 0
                    maxAbs = max(abs(min(app.overlayImage(:))), abs(max(app.overlayImage(:))));
                    valLow = double(max(-maxAbs, minLimit));  % Don't go below slider limit
                    valHigh = double(min(maxAbs, maxLimit));  % Don't go above slider limit
                    app.ThresholdSliderOverlay.Value = [valLow, valHigh];
                    app.ThresholdSliderOverlay.MajorTicks = [valLow, valHigh];
                    app.ThresholdSliderOverlay.MajorTickLabels = {num2str(valLow), num2str(valHigh)};
                else
                    app.ThresholdSliderOverlay.Value = [minLimit, maxLimit];
                    app.ThresholdSliderOverlay.MajorTicks = [minLimit, maxLimit];
                    app.ThresholdSliderOverlay.MajorTickLabels =  {num2str(minLimit), num2str(maxLimit)};
                end

                %set value boxes
                app.Overlay_low.Limits = [minLimit, maxLimit-0.001*maxLimit];
                app.Overlay_high.Limits = [minLimit+abs(0.001*minLimit), maxLimit];
                app.Overlay_low.Value = app.ThresholdSliderOverlay.Value(1);
                app.Overlay_high.Value = app.ThresholdSliderOverlay.Value(2);
                app.Overlay_low.Visible = 'on';
                app.Overlay_high.Visible = 'on';

                rng    = app.OverlaySlider.UserData;               % [trueMin trueMax]
                vals   = slider2val(app, app.OverlaySlider.Value, rng);
                app.OverlayLowVal.Value = vals(1);
                app.OverlayHighVal.Value = vals(2);

                % Position of first colorbar (already defined)
                viewerPos = app.VisualizationPanel.Position;
                colorbarX = viewerPos(1) + viewerPos(3) + 10;
                colorbarY = viewerPos(2);
                colorbarW = 40;
                colorbarH = viewerPos(4);

                % Position of second colorbar (to the right of the first)
                gapBetweenBars = 20;
                overlayX = colorbarX + colorbarW + gapBetweenBars;

                app.ColorbarPanelOverlay = uipanel(app.UIFigure);
                app.ColorbarPanelOverlay.Position = [overlayX, colorbarY, colorbarW, colorbarH];
                app.ColorbarPanelOverlay.BackgroundColor = [0 0 0];
                % Match OverlaySlider to the overlay colorbar position
                sliderW = 3;
                app.OverlaySlider.Position = [overlayX + colorbarW + 2, colorbarY, sliderW, colorbarH];
                app.OverlaySlider.Visible = 'on';
                app.ColorbarPanelOverlay.Visible = 'on';
                app.OverlayAlphaSpinner.Visible = 'on';
                app.OverlayAlphaSpinnerLabel.Visible = 'on';
                app.ThresholdSliderOverlay.Visible = "on";
                app.OverlayThresholdLabel.Visible = "on";
                app.ApplySliceMaskDropDown.Items = {'All','Base','Overlay'};
                app.TrimOverlayCheckBox.Visible = "on";
                app.OverlayHighVal.Visible = "on";
                app.OverlayLowVal.Visible = "on";
            catch
                error('... overlay image appears to have a different matrix size than base image')
                app.ColorbarPanelOverlay.Visible = 'off';
                app.FlipColormapDropDownOvelay.Visible = 'off';
                app.ColormapDropDownOverlay.Visible = "off";
                app.FlipMapLabel_2.Visible = 'off';
                app.OverlayMapLabel.Visible = 'off';
                app.ThresholdSliderOverlay.Visible = 'off';
                app.RenderingDropDownOverlay.Visible = "off";
                app.OverlaySlider.Visible = 'off';
                app.OverlayAlphaSpinner.Visible = 'off';
                app.OverlayAlphaSpinnerLabel.Visible = 'off';
                app.ThresholdSliderOverlay.Visible = "off";
                app.OverlayThresholdLabel.Visible = "off";
                app.Overlay_low.Visible = 'off';
                app.Overlay_high.Visible = 'off';
                app.overlay = 0;
                app.ApplySliceMaskDropDown.Value = 'All';
                app.ApplySliceMaskDropDown.Items = {'All'};
                app.PctLowEditField_Overlay.Visible = "off";
                app.PctHighEditField_Overlay.Visible = "off";
                app.TrimOverlayCheckBox.Visible = "off";
                app.OverlayHighVal.Visible = "off";
                app.OverlayLowVal.Visible = "off";
            end
            app.updateVisualization();
        end

        % Value changed function: ThresholdSlider
        function ThresholdSliderValueChanged(app, event)
            app.Base_low.Value = round(app.ThresholdSlider.Value(1),2);
            app.Base_high.Value = round(app.ThresholdSlider.Value(2),2);
            app.updateVisualization();
        end

        % Value changed function: ThresholdSliderOverlay
        function ThresholdSliderOverlayValueChanged(app, event)
            app.Overlay_low.Value = round(app.ThresholdSliderOverlay.Value(1),2);
            app.Overlay_high.Value = round(app.ThresholdSliderOverlay.Value(2),2);
            app.updateVisualization();
        end

        % Value changed function: BaseSlider
        function BaseSliderValueChanged(app, event)
            rng    = app.BaseSlider.UserData;               % [trueMin trueMax]
            vals   = slider2val(app, app.BaseSlider.Value, rng);
            app.BaseLowVal.Value  = round(vals(1),2);
            app.BaseHighVal.Value = round(vals(2),2);

            app.updateVisualization();
        end

        % Value changed function: OverlaySlider
        function OverlaySliderValueChanged(app, event)
            rng    = app.OverlaySlider.UserData;               % [trueMin trueMax]
            vals   = slider2val(app, app.OverlaySlider.Value, rng);
            app.OverlayLowVal.Value  = round(vals(1),2);
            app.OverlayHighVal.Value = round(vals(2),2);

            app.updateVisualization();
        end

        % Size changed function: VisualizationPanel
        function VisualizationPanelSizeChanged(app, event)
            app.viewer = viewer3d('Parent', app.VisualizationPanel);
        end

        % Value changed function: ColormapDropDown
        function ColormapDropDownValueChanged(app, event)
            app.updateVisualization();
        end

        % Value changed function: TerritoryDropDown
        function TerritoryDropDownValueChanged(app, event)
            app.updateVisualization();
        end

        % Value changed function: RenderingDropDown
        function RenderingDropDownValueChanged(app, event)
            app.updateVisualization();
        end

        % Value changed function: ApplyBrainMaskCheckBox
        function ApplyBrainMaskCheckBoxValueChanged(app, event)
            app.updateVisualization();
        end

        % Value changed function: BackgroundDropDown
        function BackgroundDropDownValueChanged(app, event)
            app.updateVisualization();
        end

        % Value changed function: LightingDropDown
        function LightingDropDownValueChanged(app, event)
            app.updateVisualization();
        end

        % Value changed function: DenoisingDropDown
        function DenoisingDropDownValueChanged(app, event)
            app.updateVisualization();
        end

        % Value changed function: FlipColormapDropDown
        function FlipColormapDropDownValueChanged(app, event)
            app.updateVisualization();
        end

        % Value changed function: ErodeBaseImageCheckBox
        function ErodeBaseImageCheckBoxValueChanged(app, event)
            value = app.ErodeBaseImageCheckBox.Value
            if value
                app.ErosionStrengthSpinner.Visible = 'on';
                app.ErosionStrengthSpinnerLabel.Visible = 'on';
            else
                app.ErosionStrengthSpinner.Visible = 'off';
                app.ErosionStrengthSpinnerLabel.Visible = 'off';
            end

            if value
                app.ProtectionThresholdSpinner.Visible = 'on';
                app.ProtectionThresholdSpinnerLabel.Visible = 'on';
            else
                app.ProtectionThresholdSpinner.Visible = 'off';
                app.ProtectionThresholdSpinnerLabel.Visible = 'on';
            end
            app.updateVisualization();
        end

        % Value changed function: ErosionStrengthSpinner
        function ErosionStrengthSpinnerValueChanged(app, event)
            app.updateVisualization();
        end

        % Value changed function: ContrastSpinner
        function ContrastSpinnerValueChanged(app, event)
            app.updateVisualization();
        end

        % Button pushed function: ExportImageButton
        function ExportImageButtonPushed(app, event)
            % -------------------------------------------------------------------------
            %  Export a high-resolution PNG of exactly what is shown in the viewer
            % -------------------------------------------------------------------------
            % ── 1. ask *where* to save ───────────────────────────────────────────────
            [file,path] = uiputfile('*.png', ...
                'Save snapshot as', ...
                fullfile(pwd,'viewer_snapshot.png'));
            if isequal(file,0),  return;  end
            pngFile = fullfile(path,file);

            % overwrite silently if it exists
            if exist(pngFile,'file'),  delete(pngFile);  end

            % ── 2. copy all visual settings from the live viewer ─────────────────────
            liveV = app.viewer;
            vProp = {'CameraPosition','CameraTarget','CameraUpVector','CameraZoom', ...
                'BackgroundColor','GradientColor','RenderingQuality', ...
                'Denoising','Lighting','ScaleBar'};

            % ── 3. assemble the volume(s) exactly as visible now ─────────────────────
            idx = max(1,app.currentBaseFrame);                    % 1 for 3-D, t-index for 4-D
            [vol,cMap,aMap,ovVol,ovCMap,ovAMap] = buildFrame(idx);

            % ── 4. show them in a *temporary*, visible window ────────────────────────
            tmpFig  = uifigure('Color',liveV.BackgroundColor,'Visible','on');
            tmpFig.Name = 'Snapshot (temporary)';
            tmpView = viewer3d('Parent',tmpFig);
            for p = 1:numel(vProp)
                tmpView.(vProp{p}) = liveV.(vProp{p});
            end

            vArgs = {'Parent',tmpView, ...
                'Colormap',cMap,'Alphamap',aMap, ...
                'Transformation',app.tform, ...
                'RenderingStyle',app.RenderingDropDown.Value};
            if app.overlay
                vArgs = [vArgs,{'OverlayData',ovVol,'OverlayColormap',ovCMap, ...
                    'OverlayRenderingStyle',app.RenderingDropDownOverlay.Value, ...
                    'OverlayAlphamap',ovAMap}];
            end
            volshow(vol,vArgs{:});

            drawnow;  pause(0.05);                       % make sure it finished rendering

            % ── 5. grab the frame, optionally up-scale, write PNG ────────────────────
            pixelScale = 2;                              % enlarge captured bitmap
            rgb = getframe(tmpFig).cdata;
            rgbHi = imresize(rgb,pixelScale);

            imwrite(rgbHi,pngFile,'png');

            % ── 6. export stand‑alone colour‑bar PNGs (no histogram) ──────────────────
            [savePath, baseName, ~] = fileparts(pngFile);   % reuse user’s filename
            scaleCB = pixelScale;                           % same 2× up‑scaling

            % --- BASE colour‑bar ------------------------------------------------------
            cmapBase = app.getCustomColormap(app.ColormapDropDown.Value);
            if strcmp(app.FlipColormapDropDown.Value,'yes')
                cmapBase = flipud(cmapBase);                % honour “Flip” toggle
            end
            cbPNG = fullfile(savePath, sprintf('%s_colorbar.png',baseName));
            writeColorbarPNG(cmapBase, cbPNG, scaleCB);

            % --- OVERLAY colour‑bar ---------------------------------------------------
            if app.overlay
                cmapOv = app.getCustomColormap(app.ColormapDropDownOverlay.Value);
                if strcmp(app.FlipColormapDropDownOvelay.Value,'yes')
                    cmapOv = flipud(cmapOv);
                end
                cbOvPNG = fullfile(savePath, sprintf('%s_overlay_colorbar.png',baseName));
                writeColorbarPNG(cmapOv, cbOvPNG, scaleCB);
            end

            % =========================================================================
            % === nested helper: save a *pure* colormap gradient to PNG ===============
            % =========================================================================
            function writeColorbarPNG(cmap, outFile, scaleFactor)

                % build an RGB image that is just the vertical gradient
                n      = size(cmap,1);          % usually 256
                width  = 24;                    % px; tweak to taste
                cbImg  = reshape(cmap, [n 1 3]);           % n×1×3
                cbImg  = repmat(cbImg, [1 width 1]);       % n×width×3

                % flip so lowest value is at the bottom (standard colour‑bar look)
                cbImg  = flipud(cbImg);

                % optional hi‑res up‑scale (nearest keeps the sharp edge)
                cbHi   = imresize(cbImg, scaleFactor, 'nearest');

                % overwrite if file exists
                if exist(outFile,'file'), delete(outFile); end
                imwrite(cbHi, outFile, 'png');
            end

            delete(tmpFig);                              % close the temp window
            uialert(app.UIFigure,'Snapshot saved.','Done');

            % =========================================================================
            % === nested helper (abridged from makeBaseMovieHiRes) =====================
            % =========================================================================
            function [vol,cMap,aMap,ovVol,ovCMap,ovAMap] = buildFrame(frameIdx)

                % ---------- base volume ---------------------------------------------
                frame = app.sourceDataImage(:,:,:,frameIdx);
                if app.TrimBaseCheckBox.Value
                    frame = clipOutliers(frame, ...
                        app.PctLowEditField_Base.Value, ...
                        app.PctHighEditField_Base.Value);
                end

                rng   = app.BaseSlider.UserData;
                vMin  = rng(1)+app.BaseSlider.Value(1)*diff(rng);
                vMax  = rng(1)+app.BaseSlider.Value(2)*diff(rng);
                vol   = (frame-vMin)/(vMax-vMin);
                vol   = max(min(vol,1),0);

                % ---------- masks ----------------------------------------------------
                thr   = round(app.ThresholdSlider.Value);
                mBase = frame>=thr(1) & frame<=thr(2);

                if app.existMasks
                    switch app.TerritoryDropDown.Value
                        case 'ALL',  mReg = true(size(frame));
                        otherwise,   mReg = app.masks.(app.TerritoryDropDown.Value);
                    end
                else
                    mReg = true(size(frame));
                end
                mAll = mBase & mReg;
                if app.ApplyBrainMaskCheckBox.Value,  mAll = mAll & app.WBmask;  end

                % slice mask
                xL=app.ORI1Slider.Value; yL=app.ORI2Slider.Value; zL=app.ORI3Slider.Value;
                xS=app.ORI1Slider.UserData; yS=app.ORI2Slider.UserData; zS=app.ORI3Slider.UserData;
                xI=max(1,min(round(xL),xS)); yI=max(1,min(round(yL),yS)); zI=max(1,min(round(zL),zS));
                mSlice = false(size(frame));
                mSlice(yI(1):yI(2),xI(1):xI(2),zI(1):zI(2)) = true;

                if strcmp(app.ApplySliceMaskDropDown.Value,'Base') || ...
                        strcmp(app.ApplySliceMaskDropDown.Value,'All')
                    mAll = mAll & mSlice;
                end

                vol(~mAll) = 0;
                vol(1,1,1)=0; vol(1,1,2)=1;               % lock colour-scale

                % ---------- base colour / alpha -------------------------------------
                cMap = app.getCustomColormap(app.ColormapDropDown.Value);
                if strcmp(app.FlipColormapDropDown.Value,'yes'),  cMap = flip(cMap);  end
                aMap = (linspace(0,1,256).^app.ContrastSpinner.Value) * ...
                    app.BaseAlphaSpinner.Value;
                aMap(1)=0;

                % ---------- overlay --------------------------------------------------
                ovVol=[]; ovCMap=[]; ovAMap=[];
                if app.overlay
                    ovData = app.overlayImage;
                    if app.ApplyBrainMaskCheckBox.Value,  ovData = ovData.*app.WBmask;  end

                    oRng = app.OverlaySlider.UserData;
                    oMin = oRng(1)+app.OverlaySlider.Value(1)*diff(oRng);
                    oMax = oRng(1)+app.OverlaySlider.Value(2)*diff(oRng);
                    ovVol = (ovData-oMin)/(oMax-oMin);  ovVol = max(min(ovVol,1),0);

                    thrOv = round(app.ThresholdSliderOverlay.Value);
                    mOv   = ovData>=thrOv(1) & ovData<=thrOv(2);
                    if strcmp(app.ApplySliceMaskDropDown.Value,'Overlay') || ...
                            strcmp(app.ApplySliceMaskDropDown.Value,'All')
                        mOv = mOv & mSlice;
                    end
                    ovVol(~mOv) = 0;

                    ovCMap = app.getCustomColormap(app.ColormapDropDownOverlay.Value);
                    if strcmp(app.FlipColormapDropDownOvelay.Value,'yes')
                        ovCMap = flip(ovCMap);
                    end
                    ovAMap = linspace(0,1,256) * app.OverlayAlphaSpinner.Value;
                    zIdx   = round((0-oMin)/(oMax-oMin)*255)+1;
                    zIdx   = max(1,min(256,zIdx));  ovAMap(zIdx)=0;
                end

                saveFile = 'viewer_state_snapshot.mat';
                stateFile = fullfile(saveDir,saveFile);
                app.saveViewerState(stateFile);

            end
            % ================= end ExportImageButtonPushed ===========================

        end

        % Value changed function: ProtectionThresholdSpinner
        function ProtectionThresholdSpinnerValueChanged(app, event)
            app.updateVisualization();
        end

        % Value changed function: ColormapDropDownOverlay
        function ColormapDropDownOverlayValueChanged(app, event)
            app.updateVisualization();
        end

        % Value changed function: FlipColormapDropDownOvelay
        function FlipColormapDropDownOvelayValueChanged(app, event)
            app.updateVisualization();
        end

        % Value changed function: RenderingDropDownOverlay
        function RenderingDropDownOverlayValueChanged(app, event)
            app.updateVisualization();
        end

        % Value changing function: ThresholdSliderOverlay
        function ThresholdSliderOverlayValueChanging(app, event)
            app.updateVisualization();
        end

        % Value changed function: BaseAlphaSpinner
        function BaseAlphaSpinnerValueChanged(app, event)
            app.updateVisualization();
        end

        % Value changed function: OverlayAlphaSpinner
        function OverlayAlphaSpinnerValueChanged(app, event)
            app.updateVisualization();
        end

        % Value changed function: ORI1Slider
        function ORI1SliderValueChanged(app, event)
            app.updateVisualization();
            app.ORI1_low.Value = round(app.ORI1Slider.Value(1));
            app.ORI1_high.Value = round(app.ORI1Slider.Value(2));
        end

        % Value changed function: ORI2Slider
        function ORI2SliderValueChanged(app, event)
            app.updateVisualization();
            app.ORI2_low.Value = round(app.ORI2Slider.Value(1));
            app.ORI2_high.Value = round(app.ORI2Slider.Value(2));
        end

        % Value changed function: ORI3Slider
        function ORI3SliderValueChanged(app, event)
            app.updateVisualization();
            app.ORI3_low.Value = round(app.ORI3Slider.Value(1));
            app.ORI3_high.Value = round(app.ORI3Slider.Value(2));
        end

        % Value changed function: ORI1_low
        function ORI1_lowValueChanged(app, event)
            value = app.ORI1_low.Value;
            if value > app.ORI1_high.Value
                app.ORI1_low.Value = floor(app.ORI1_high.Value-1);
                app.ORI1Slider.Value(1) = app.ORI1_low.Value;
            else
                app.ORI1Slider.Value(1) = app.ORI1_low.Value;
            end
            app.updateVisualization();
        end

        % Value changed function: ORI2_low
        function ORI2_lowValueChanged(app, event)
            value = app.ORI2_low.Value;
            if value > app.ORI2_high.Value
                app.ORI2_low.Value = floor(app.ORI2_high.Value-1);
                app.ORI2Slider.Value(1) = app.ORI2_low.Value;
            else
                app.ORI2Slider.Value(1) = app.ORI2_low.Value;
            end
            app.updateVisualization();
        end

        % Value changed function: ORI3_low
        function ORI3_lowValueChanged(app, event)
            value = app.ORI3_low.Value;
            if value > app.ORI3_high.Value
                app.ORI3_low.Value = floor(app.ORI3_high.Value-1);
                app.ORI3Slider.Value(1) = app.ORI3_low.Value;
            else
                app.ORI3Slider.Value(1) = app.ORI3_low.Value;
            end
            app.updateVisualization();
        end

        % Value changed function: ORI1_high
        function ORI1_highValueChanged(app, event)
            value = app.ORI1_high.Value;
            if value < app.ORI1_low.Value
                app.ORI1_high.Value = floor(app.ORI1_low.Value+1);
                app.ORI1Slider.Value(2) = app.ORI1_high.Value;
            else
                app.ORI1Slider.Value(2) = app.ORI1_high.Value;
            end
            app.updateVisualization();
        end

        % Value changed function: ORI2_high
        function ORI2_highValueChanged(app, event)
            value = app.ORI2_high.Value;
            if value < app.ORI2_low.Value
                app.ORI2_high.Value = floor(app.ORI2_low.Value+1);
                app.ORI2Slider.Value(2) = app.ORI2_high.Value;
            else
                app.ORI2Slider.Value(2) = app.ORI2_high.Value;
            end
            app.updateVisualization();
        end

        % Value changed function: ORI3_high
        function ORI3_highValueChanged(app, event)
            value = app.ORI3_high.Value;
            if value < app.ORI3_low.Value
                app.ORI3_high.Value = floor(app.ORI3_low.Value+1);
                app.ORI3Slider.Value(2) = app.ORI3_high.Value;
            else
                app.ORI3Slider.Value(2) = app.ORI3_high.Value;
            end
            app.updateVisualization();
        end

        % Value changed function: Base_low
        function Base_lowValueChanged(app, event)
            value = app.Base_low.Value;
            app.ThresholdSlider.Value(1) = value;
            app.updateVisualization();
        end

        % Value changed function: Base_high
        function Base_highValueChanged(app, event)
            value = app.Base_high.Value;
            app.ThresholdSlider.Value(2) = value;
            app.updateVisualization();
        end

        % Value changed function: Overlay_low
        function Overlay_lowValueChanged(app, event)
            value = app.Overlay_low.Value;
            app.ThresholdSliderOverlay.Value(1) = value;
            app.updateVisualization();
        end

        % Value changed function: Overlay_high
        function Overlay_highValueChanged(app, event)
            value = app.Overlay_high.Value;
            app.ThresholdSliderOverlay.Value(2) = value;
            app.updateVisualization();
        end

        % Value changed function: ApplySliceMaskDropDown
        function ApplySliceMaskDropDownValueChanged(app, event)
            app.updateVisualization();
        end

        % Value changed function: TrimBaseCheckBox
        function TrimBaseCheckBoxValueChanged(app, event)
            value = app.TrimBaseCheckBox.Value;

            % <-- get current 3-D frame
            origFrame = app.sourceDataImage;
            if ndims(origFrame)==4
                origFrame = origFrame(:,:,:,app.currentBaseFrame);
            end

            if value          % ----------------- TRIM ON -----------------
                app.PctLowEditField_Base.Visible  = "on";
                app.PctHighEditField_Base.Visible = "on";
                app.PctHighBase.Visible           = "on";
                app.PctLowBase.Visible            = "on";

                app.dataImage = clipOutliers(origFrame, ...
                    app.PctLowEditField_Base.Value , ...
                    app.PctHighEditField_Base.Value);
            else              % ----------------- TRIM OFF ----------------
                app.PctLowEditField_Base.Visible  = "off";
                app.PctHighEditField_Base.Visible = "off";
                app.PctHighBase.Visible           = "off";
                app.PctLowBase.Visible            = "off";

                app.dataImage = origFrame;                       % restore
            end

            % --- refresh histogram & view
            [app.baseHistCounts, app.baseHistEdges] = computeHistogram(app, app.dataImage);
            app.updateVisualization();
        end

        % Value changed function: TrimOverlayCheckBox
        function TrimOverlayCheckBoxValueChanged(app, event)
            value = app.TrimOverlayCheckBox.Value;
            if value
                %set visibility
                app.PctLowEditField_Overlay.Visible = "on";
                app.PctHighEditField_Overlay.Visible = "on";
                app.PctHighOverlay.Visible = "on";
                app.PctLowOverlay.Visible = "on";
                % -------- trim  ---------------------------------------------------------
                % Trim
                app.overlayImage = clipOutliers(app.sourceOverlayImage, app.PctLowEditField_Overlay.Value, app.PctHighEditField_Overlay.Value);
                % -----------------------------------------------------------------------
            else
                %set visibility
                app.PctLowEditField_Overlay.Visible = "off";
                app.PctHighEditField_Overlay.Visible = "off";
                app.PctHighOverlay.Visible = "off";
                app.PctLowOverlay.Visible = "off";
                app.overlayImage = app.sourceOverlayImage;
            end
            % -------------- Calculate Histogram -----------------
            [app.overlayHistCounts, app.overlayHistEdges] = computeHistogram(app, app.overlayImage);

            app.updateVisualization();

        end

        % Value changed function: PctLowEditField_Base
        function PctLowEditField_BaseValueChanged(app, event)
            if ~app.TrimBaseCheckBox.Value,  return;  end

            origFrame = app.sourceDataImage;
            if ndims(origFrame)==4
                origFrame = origFrame(:,:,:,app.currentBaseFrame);
            end

            app.dataImage = clipOutliers(origFrame, ...
                app.PctLowEditField_Base.Value , ...
                app.PctHighEditField_Base.Value);

            [app.baseHistCounts, app.baseHistEdges] = computeHistogram(app, app.dataImage);
            app.updateVisualization();
        end

        % Value changed function: PctHighEditField_Base
        function PctHighEditField_BaseValueChanged(app, event)
            if ~app.TrimBaseCheckBox.Value,  return;  end

            origFrame = app.sourceDataImage;
            if ndims(origFrame)==4
                origFrame = origFrame(:,:,:,app.currentBaseFrame);
            end

            app.dataImage = clipOutliers(origFrame, ...
                app.PctLowEditField_Base.Value , ...
                app.PctHighEditField_Base.Value);

            [app.baseHistCounts, app.baseHistEdges] = computeHistogram(app, app.dataImage);
            app.updateVisualization();
        end

        % Value changed function: PctLowEditField_Overlay
        function PctLowEditField_OverlayValueChanged(app, event)
            value = app.TrimOverlayCheckBox.Value;
            if value
                %set visibility
                app.PctLowEditField_Overlay.Visible = "on";
                app.PctHighEditField_Overlay.Visible = "on";
                % -------- trim  ---------------------------------------------------------
                % Trim
                app.overlayImage = clipOutliers(app.sourceOverlayImage, app.PctLowEditField_Overlay.Value, app.PctHighEditField_Overlay.Value);
                % -----------------------------------------------------------------------
            else
                %set visibility
                app.PctLowEditField_Overlay.Visible = "off";
                app.PctHighEditField_Overlay.Visible = "off";
                app.overlayImage = app.sourceOverlayImage;
            end
            % -------------- Calculate Histogram -----------------
            [app.overlayHistCounts, app.overlayHistEdges] = computeHistogram(app, app.overlayImage);

            app.updateVisualization();

        end

        % Value changed function: PctHighEditField_Overlay
        function PctHighEditField_OverlayValueChanged(app, event)
            value = app.TrimOverlayCheckBox.Value;
            if value
                %set visibility
                app.PctLowEditField_Overlay.Visible = "on";
                app.PctHighEditField_Overlay.Visible = "on";
                % -------- trim  ---------------------------------------------------------
                % Trim
                app.overlayImage = clipOutliers(app.sourceOverlayImage, app.PctLowEditField_Overlay.Value, app.PctHighEditField_Overlay.Value);
                % -----------------------------------------------------------------------
            else
                %set visibility
                app.PctLowEditField_Overlay.Visible = "off";
                app.PctHighEditField_Overlay.Visible = "off";
                app.overlayImage = app.sourceOverlayImage;
            end
            % -------------- Calculate Histogram -----------------
            [app.overlayHistCounts, app.overlayHistEdges] = computeHistogram(app, app.overlayImage);

            app.updateVisualization();

        end

        % Value changed function: BaseHighVal
        function BaseHighValValueChanged(app, event)
            rng  = app.BaseSlider.UserData;
            low  = app.BaseLowVal.Value;
            high = app.BaseHighVal.Value;

            % sanity – keep inside range and ordered
            low  = max(rng(1), min(low,  high-eps));
            high = min(rng(2), max(high, low +eps));

            app.BaseLowVal.Value  = round(low,2);      % (may have been clipped)
            app.BaseHighVal.Value = round(high,2);

            app.BaseSlider.Value = val2slider(app, [low high], rng);

            app.updateVisualization();

        end

        % Value changed function: BaseLowVal
        function BaseLowValValueChanged(app, event)
            rng  = app.BaseSlider.UserData;
            low  = app.BaseLowVal.Value;
            high = app.BaseHighVal.Value;

            % sanity – keep inside range and ordered
            low  = max(rng(1), min(low,  high-eps));
            high = min(rng(2), max(high, low +eps));

            app.BaseLowVal.Value  = round(low,2);      % (may have been clipped)
            app.BaseHighVal.Value = round(high,2);

            app.BaseSlider.Value = val2slider(app, [low high], rng);

            app.updateVisualization();

        end

        % Value changed function: OverlayLowVal
        function OverlayLowValValueChanged(app, event)
            rng  = app.OverlaySlider.UserData;
            low  = app.OverlayLowVal.Value;
            high = app.OverlayHighVal.Value;

            % sanity – keep inside range and ordered
            low  = max(rng(1), min(low,  high-eps));
            high = min(rng(2), max(high, low +eps));

            app.OverlayLowVal.Value  = round(low,2);      % (may have been clipped)
            app.OverlayHighVal.Value = round(high,2);

            app.OverlaySlider.Value = val2slider(app, [low high], rng);

            app.updateVisualization();

        end

        % Value changed function: OverlayHighVal
        function OverlayHighValValueChanged(app, event)
            rng  = app.OverlaySlider.UserData;
            low  = app.OverlayLowVal.Value;
            high = app.OverlayHighVal.Value;

            % sanity – keep inside range and ordered
            low  = max(rng(1), min(low,  high-eps));
            high = min(rng(2), max(high, low +eps));

            app.OverlayLowVal.Value  = round(low,2);      % (may have been clipped)
            app.OverlayHighVal.Value = round(high,2);

            app.OverlaySlider.Value = val2slider(app, [low high], rng);

            app.updateVisualization();

        end

        % Callback function: UIFigure, VolumeSlider
        function VolumeSliderValueChanged(app, event)
            app.currentBaseFrame = round(app.VolumeSlider.Value);
            app.VolumeVal.Value  = app.currentBaseFrame;
            % current raw frame
            origFrame = app.sourceDataImage;
            if ndims(origFrame)==4
                origFrame = origFrame(:,:,:,app.currentBaseFrame);
            end

            % apply trim if active
            if app.TrimBaseCheckBox.Value
                app.dataImage = clipOutliers(origFrame, ...
                    app.PctLowEditField_Base.Value , ...
                    app.PctHighEditField_Base.Value);
            else
                app.dataImage = origFrame;
            end

            [app.baseHistCounts, app.baseHistEdges] = computeHistogram(app, app.dataImage);
            app.updateVisualization();
        end

        % Value changed function: VolumeVal
        function VolumeValValueChanged(app, event)
            value = app.VolumeVal.Value;
        end

        % Button pushed function: ExportMovieButton
        function ExportMovieButtonPushed(app, event)
            app.makeBaseMovieHiRes();
        end

        % Button pushed function: ResetSlidersButton
        function ResetSlidersButtonPushed(app, event)
            app.ORI1Slider.Value(1) = app.ORI1Slider.Limits(1); app.ORI1Slider.Value(2) = app.ORI1Slider.Limits(2);
            app.ORI2Slider.Value(1) = app.ORI2Slider.Limits(1); app.ORI2Slider.Value(2) = app.ORI2Slider.Limits(2);
            app.ORI3Slider.Value(1) = app.ORI3Slider.Limits(1); app.ORI3Slider.Value(2) = app.ORI3Slider.Limits(2);

            app.ORI1_low.Value = app.ORI1Slider.Value(1); app.ORI1_high.Value = app.ORI1Slider.Value(2);
            app.ORI2_low.Value = app.ORI2Slider.Value(1); app.ORI2_high.Value = app.ORI2Slider.Value(2);
            app.ORI3_low.Value = app.ORI3Slider.Value(1); app.ORI3_high.Value = app.ORI3Slider.Value(2);

            app.updateVisualization();

        end

        % Button pushed function: SaveStateButton
        function SaveStateButtonPushed(app, event)

            [saveFile,saveDir] = uiputfile( ...
                'viewer_state.mat', ...
                'Save viewer state as');       % pops up a dialog
            if isequal(saveFile,0),  return; end   % user hit <Cancel>

            stateFile = fullfile(saveDir,saveFile);

            app.saveViewerState(stateFile);

        end

        % Button pushed function: LoadStateButton
        function LoadStateButtonPushed(app, event)
            app.loadViewerState(); 
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @VolumeSliderValueChanged, true);
            app.LoadBaseButton = uibutton(app.UIFigure, 'push');
            app.LoadBaseButton.ButtonPushedFcn = createCallbackFcn(app, @LoadBaseButtonPushed, true);
            app.LoadBaseButton.Tooltip = {'Load base image. If there is a mask present in the same folder it will also be loaded but it must contain ''*_brain_mask.nii*'' - the extension can also be .nii.gz. Note, base image can be 3D OR 4D. '};
            app.LoadBaseButton.Text = 'Load Base';
            app.ColorMapDropDownLabel = uilabel(app.UIFigure);
            app.ColorMapDropDownLabel.Text = 'Color Map';
            app.ColormapDropDown = uidropdown(app.UIFigure);
            app.ColormapDropDown.Items = {'Inferno', 'RdYlBl', 'RdYlGn', 'Jet', 'Turbo', 'Spectral', 'Devon', 'grayC', 'Hawaii', 'Viridis', 'Plasma', 'Magma', 'Lajolla', 'Buda', 'Nuuk', 'Roma', 'DSA'};
            app.ColormapDropDown.ValueChangedFcn = createCallbackFcn(app, @ColormapDropDownValueChanged, true);
            app.ColormapDropDown.Value = 'Inferno';
            app.ThresholdSlider = uislider(app.UIFigure, 'range');
            app.ThresholdSlider.Limits = [1 2];
            app.ThresholdSlider.ValueChangedFcn = createCallbackFcn(app, @ThresholdSliderValueChanged, true);
            app.ThresholdSlider.MinorTicks = [];
            app.ThresholdSlider.Visible = 'off';
            app.ThresholdSlider.Tooltip = {'The slider determines which data from the base image is made visible. Any values outside the high and low range are not visible. This slider has no effect on color scaling. '};
            app.ThresholdSlider.Value = [1 2];
            app.VisualizationPanel = uipanel(app.UIFigure);
            app.VisualizationPanel.SizeChangedFcn = createCallbackFcn(app, @VisualizationPanelSizeChanged, true);
            app.TerritoryDropDownLabel = uilabel(app.UIFigure);
            app.TerritoryDropDownLabel.Visible = 'off';
            app.TerritoryDropDownLabel.Text = 'Territory';
            app.TerritoryDropDown = uidropdown(app.UIFigure);
            app.TerritoryDropDown.Items = {'ALL', 'Anterior_L', 'Anterior_R', 'PCA', 'VB', 'Ventricles'};
            app.TerritoryDropDown.ValueChangedFcn = createCallbackFcn(app, @TerritoryDropDownValueChanged, true);
            app.TerritoryDropDown.Visible = 'off';
            app.TerritoryDropDown.Value = 'ALL';
            app.RenderingDropDownLabel = uilabel(app.UIFigure);
            app.RenderingDropDownLabel.Text = 'Rendering';
            app.RenderingDropDown = uidropdown(app.UIFigure);
            app.RenderingDropDown.Items = {'CinematicRendering', 'VolumeRendering', 'LightScattering', 'GradientOpacity', 'Isosurface'};
            app.RenderingDropDown.ValueChangedFcn = createCallbackFcn(app, @RenderingDropDownValueChanged, true);
            app.RenderingDropDown.Tooltip = {'good options are cinematic and light scattering'};
            app.RenderingDropDown.Value = 'CinematicRendering';
            app.ColorbarPanel = uipanel(app.UIFigure);
            app.ColorbarPanel.Visible = 'off';
            app.ApplyBrainMaskCheckBox = uicheckbox(app.UIFigure);
            app.ApplyBrainMaskCheckBox.ValueChangedFcn = createCallbackFcn(app, @ApplyBrainMaskCheckBoxValueChanged, true);
            app.ApplyBrainMaskCheckBox.Visible = 'off';
            app.ApplyBrainMaskCheckBox.Text = 'Apply Brain Mask';
            app.BackgroundDropDownLabel = uilabel(app.UIFigure);
            app.BackgroundDropDownLabel.Text = 'Background';
            app.BackgroundDropDown = uidropdown(app.UIFigure);
            app.BackgroundDropDown.Items = {'Black', 'Dark', 'Light', 'White'};
            app.BackgroundDropDown.ValueChangedFcn = createCallbackFcn(app, @BackgroundDropDownValueChanged, true);
            app.BackgroundDropDown.Tooltip = {'Switch between predefined background presets.'};
            app.BackgroundDropDown.Value = 'Black';
            app.LightingDropDownLabel = uilabel(app.UIFigure);
            app.LightingDropDownLabel.Text = 'Lighting';
            app.LightingDropDown = uidropdown(app.UIFigure);
            app.LightingDropDown.Items = {'on', 'off'};
            app.LightingDropDown.ValueChangedFcn = createCallbackFcn(app, @LightingDropDownValueChanged, true);
            app.LightingDropDown.Tooltip = {'Adds lighting, GPU intensive'};
            app.LightingDropDown.Value = 'off';
            app.DenoisingDropDownLabel = uilabel(app.UIFigure);
            app.DenoisingDropDownLabel.Text = 'Denoising';
            app.DenoisingDropDown = uidropdown(app.UIFigure);
            app.DenoisingDropDown.Items = {'on', 'off'};
            app.DenoisingDropDown.ValueChangedFcn = createCallbackFcn(app, @DenoisingDropDownValueChanged, true);
            app.DenoisingDropDown.Tooltip = {'Denoises volume, GPU intensive'};
            app.DenoisingDropDown.Value = 'off';
            app.FlipMapLabel = uilabel(app.UIFigure);
            app.FlipMapLabel.Text = 'Flip Map';
            app.FlipColormapDropDown = uidropdown(app.UIFigure);
            app.FlipColormapDropDown.Items = {'no', 'yes'};
            app.FlipColormapDropDown.ValueChangedFcn = createCallbackFcn(app, @FlipColormapDropDownValueChanged, true);
            app.FlipColormapDropDown.Tooltip = {'inverts colormap'};
            app.FlipColormapDropDown.Value = 'yes';
            app.ErosionStrengthSpinnerLabel = uilabel(app.UIFigure);
            app.ErosionStrengthSpinnerLabel.Visible = 'off';
            app.ErosionStrengthSpinnerLabel.Text = 'Erosion Strength';
            app.ErosionStrengthSpinner = uispinner(app.UIFigure);
            app.ErosionStrengthSpinner.Step = 0.025;
            app.ErosionStrengthSpinner.Limits = [0.025 1];
            app.ErosionStrengthSpinner.ValueChangedFcn = createCallbackFcn(app, @ErosionStrengthSpinnerValueChanged, true);
            app.ErosionStrengthSpinner.Visible = 'off';
            app.ErosionStrengthSpinner.Tooltip = {'controls the amount of erosion - this can then be tuned with the protection threshold'};
            app.ErosionStrengthSpinner.Value = 0.1;
            app.ErodeBaseImageCheckBox = uicheckbox(app.UIFigure);
            app.ErodeBaseImageCheckBox.ValueChangedFcn = createCallbackFcn(app, @ErodeBaseImageCheckBoxValueChanged, true);
            app.ErodeBaseImageCheckBox.Tooltip = {'The base image can be eroded considering a protection threshold to preserve structures of specified sizes. This was intended for MRA data.'};
            app.ErodeBaseImageCheckBox.Text = 'Erode Base Image';
            app.ExportImageButton = uibutton(app.UIFigure, 'push');
            app.ExportImageButton.ButtonPushedFcn = createCallbackFcn(app, @ExportImageButtonPushed, true);
            app.ExportImageButton.Visible = 'off';
            app.ExportImageButton.Text = 'Export Image';
            app.ProtectionThresholdSpinnerLabel = uilabel(app.UIFigure);
            app.ProtectionThresholdSpinnerLabel.Visible = 'off';
            app.ProtectionThresholdSpinnerLabel.Text = 'Protection Threshold';
            app.ProtectionThresholdSpinner = uispinner(app.UIFigure);
            app.ProtectionThresholdSpinner.Limits = [1 5];
            app.ProtectionThresholdSpinner.ValueChangedFcn = createCallbackFcn(app, @ProtectionThresholdSpinnerValueChanged, true);
            app.ProtectionThresholdSpinner.Visible = 'off';
            app.ProtectionThresholdSpinner.Tooltip = {'Protective erosion will maintain vessels - larger value means more vessels'};
            app.ProtectionThresholdSpinner.Value = 1;
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'MRAwesome.png');
            app.ContrastSpinnerLabel = uilabel(app.UIFigure);
            app.ContrastSpinnerLabel.Text = 'Contrast';
            app.ContrastSpinner = uispinner(app.UIFigure);
            app.ContrastSpinner.Step = 0.2;
            app.ContrastSpinner.Limits = [0 3];
            app.ContrastSpinner.ValueChangedFcn = createCallbackFcn(app, @ContrastSpinnerValueChanged, true);
            app.ContrastSpinner.Tooltip = {'modifies the alpha value - higher values give lighter vessels with more contrast'};
            app.ContrastSpinner.Value = 1;
            app.LoadOverlayButton = uibutton(app.UIFigure, 'push');
            app.LoadOverlayButton.ButtonPushedFcn = createCallbackFcn(app, @LoadOverlayButtonPushed, true);
            app.LoadOverlayButton.Visible = 'off';
            app.LoadOverlayButton.Tooltip = {'Loads an overlay image. The overlay will be resampled and interpolated to the dimensions of the base image. Overlay can only be 3D.'};
            app.LoadOverlayButton.Text = 'Load Overlay';
            app.OverlayMapLabel = uilabel(app.UIFigure);
            app.OverlayMapLabel.Visible = 'off';
            app.OverlayMapLabel.Text = 'Overlay Map';
            app.ColormapDropDownOverlay = uidropdown(app.UIFigure);
            app.ColormapDropDownOverlay.Items = {'Inferno', 'RdYlBl', 'RdYlGn', 'Jet', 'Turbo', 'Spectral', 'Devon', 'grayC', 'Hawaii', 'Viridis', 'Plasma', 'Magma', 'Lajolla', 'Buda', 'Nuuk', 'Roma', 'DSA'};
            app.ColormapDropDownOverlay.ValueChangedFcn = createCallbackFcn(app, @ColormapDropDownOverlayValueChanged, true);
            app.ColormapDropDownOverlay.Visible = 'off';
            app.ColormapDropDownOverlay.Value = 'Viridis';
            app.FlipMapLabel_2 = uilabel(app.UIFigure);
            app.FlipMapLabel_2.Tag = 'Label';
            app.FlipMapLabel_2.Visible = 'off';
            app.FlipMapLabel_2.Text = 'Flip Map';
            app.FlipColormapDropDownOvelay = uidropdown(app.UIFigure);
            app.FlipColormapDropDownOvelay.Items = {'no', 'yes'};
            app.FlipColormapDropDownOvelay.ValueChangedFcn = createCallbackFcn(app, @FlipColormapDropDownOvelayValueChanged, true);
            app.FlipColormapDropDownOvelay.Visible = 'off';
            app.FlipColormapDropDownOvelay.Tooltip = {'inverts colormap'};
            app.FlipColormapDropDownOvelay.Value = 'yes';
            app.ColorbarPanelOverlay = uipanel(app.UIFigure);
            app.ColorbarPanelOverlay.Visible = 'off';
            app.ThresholdSliderOverlay = uislider(app.UIFigure, 'range');
            app.ThresholdSliderOverlay.Limits = [1 2];
            app.ThresholdSliderOverlay.MajorTicks = [1 1.2 1.4 1.6 1.8 2];
            app.ThresholdSliderOverlay.MajorTickLabels = {'1', '1.2', '1.4', '1.6', '1.8', '2'};
            app.ThresholdSliderOverlay.ValueChangedFcn = createCallbackFcn(app, @ThresholdSliderOverlayValueChanged, true);
            app.ThresholdSliderOverlay.ValueChangingFcn = createCallbackFcn(app, @ThresholdSliderOverlayValueChanging, true);
            app.ThresholdSliderOverlay.MinorTicks = [];
            app.ThresholdSliderOverlay.Visible = 'off';
            app.ThresholdSliderOverlay.Tooltip = {'The slider determines which data from the base image is made visible. Any values outside the high and low range are not visible. This slider has no effect on color scaling. '};
            app.ThresholdSliderOverlay.Value = [1 2];
            app.BaseThresholdLabel = uilabel(app.UIFigure);
            app.BaseThresholdLabel.Visible = 'off';
            app.BaseThresholdLabel.Text = 'Base Threshold';
            app.RenderingDropDown_2Label = uilabel(app.UIFigure);
            app.RenderingDropDown_2Label.Visible = 'off';
            app.RenderingDropDown_2Label.Text = 'Rendering';
            app.RenderingDropDownOverlay = uidropdown(app.UIFigure);
            app.RenderingDropDownOverlay.Items = {'VolumeOverlay', 'GradientOverlay', 'LabelOverlay'};
            app.RenderingDropDownOverlay.ValueChangedFcn = createCallbackFcn(app, @RenderingDropDownOverlayValueChanged, true);
            app.RenderingDropDownOverlay.Visible = 'off';
            app.RenderingDropDownOverlay.Tooltip = {''};
            app.RenderingDropDownOverlay.Value = 'VolumeOverlay';
            app.BaseSlider = uislider(app.UIFigure, 'range');
            app.BaseSlider.Limits = [0 1];
            app.BaseSlider.MajorTicks = [];
            app.BaseSlider.ValueChangedFcn = createCallbackFcn(app, @BaseSliderValueChanged, true);
            app.BaseSlider.MinorTicks = [];
            app.BaseSlider.Visible = 'off';
            app.BaseSlider.Tooltip = {'This slider can be used to scale the colormap. The high and low slider values do not currently align with the histogram shown in the colorbar - this should only bse used as a reference.'};
            app.BaseSlider.Value = [0 1];
            app.OverlaySlider = uislider(app.UIFigure, 'range');
            app.OverlaySlider.Limits = [0 1];
            app.OverlaySlider.MajorTicks = [];
            app.OverlaySlider.ValueChangedFcn = createCallbackFcn(app, @OverlaySliderValueChanged, true);
            app.OverlaySlider.MinorTicks = [];
            app.OverlaySlider.Visible = 'off';
            app.OverlaySlider.Tooltip = {'This slider can be used to scale the colormap. The high and low slider values do not currently align with the histogram shown in the colorbar - this should only bse used as a reference.'};
            app.OverlaySlider.Value = [0 1];
            app.OverlayThresholdLabel = uilabel(app.UIFigure);
            app.OverlayThresholdLabel.Visible = 'off';
            app.OverlayThresholdLabel.Text = 'Overlay Threshold';
            app.BaseAlphaSpinnerLabel = uilabel(app.UIFigure);
            app.BaseAlphaSpinnerLabel.Visible = 'off';
            app.BaseAlphaSpinnerLabel.Text = 'Base Alpha';
            app.BaseAlphaSpinner = uispinner(app.UIFigure);
            app.BaseAlphaSpinner.Step = 0.1;
            app.BaseAlphaSpinner.Limits = [0 1];
            app.BaseAlphaSpinner.ValueChangedFcn = createCallbackFcn(app, @BaseAlphaSpinnerValueChanged, true);
            app.BaseAlphaSpinner.Visible = 'off';
            app.BaseAlphaSpinner.Tooltip = {'Sets the transparency of the base image.'};
            app.BaseAlphaSpinner.Value = 0.9;
            app.OverlayAlphaSpinnerLabel = uilabel(app.UIFigure);
            app.OverlayAlphaSpinnerLabel.Visible = 'off';
            app.OverlayAlphaSpinnerLabel.Text = 'Overlay Alpha';
            app.OverlayAlphaSpinner = uispinner(app.UIFigure);
            app.OverlayAlphaSpinner.Step = 0.1;
            app.OverlayAlphaSpinner.Limits = [0 1];
            app.OverlayAlphaSpinner.ValueChangedFcn = createCallbackFcn(app, @OverlayAlphaSpinnerValueChanged, true);
            app.OverlayAlphaSpinner.Visible = 'off';
            app.OverlayAlphaSpinner.Tooltip = {'Sets the transparency of the overlay image.'};
            app.OverlayAlphaSpinner.Value = 0.5;
            app.ORI1SliderLabel = uilabel(app.UIFigure);
            app.ORI1SliderLabel.Visible = 'off';
            app.ORI1SliderLabel.Text = 'ORI1';
            app.ORI1Slider = uislider(app.UIFigure, 'range');
            app.ORI1Slider.Limits = [0 1];
            app.ORI1Slider.MajorTicks = [];
            app.ORI1Slider.ValueChangedFcn = createCallbackFcn(app, @ORI1SliderValueChanged, true);
            app.ORI1Slider.MinorTicks = [];
            app.ORI1Slider.Visible = 'off';
            app.ORI1Slider.Value = [0 1];
            app.ORI2SliderLabel = uilabel(app.UIFigure);
            app.ORI2SliderLabel.Visible = 'off';
            app.ORI2SliderLabel.Text = 'ORI2';
            app.ORI2Slider = uislider(app.UIFigure, 'range');
            app.ORI2Slider.Limits = [0 1];
            app.ORI2Slider.MajorTicks = [];
            app.ORI2Slider.ValueChangedFcn = createCallbackFcn(app, @ORI2SliderValueChanged, true);
            app.ORI2Slider.MinorTicks = [];
            app.ORI2Slider.Visible = 'off';
            app.ORI2Slider.Value = [0 1];
            app.ORI3SliderLabel = uilabel(app.UIFigure);
            app.ORI3SliderLabel.Visible = 'off';
            app.ORI3SliderLabel.Text = 'ORI3';
            app.ORI3Slider = uislider(app.UIFigure, 'range');
            app.ORI3Slider.Limits = [0 1];
            app.ORI3Slider.MajorTicks = [];
            app.ORI3Slider.ValueChangedFcn = createCallbackFcn(app, @ORI3SliderValueChanged, true);
            app.ORI3Slider.MinorTicks = [];
            app.ORI3Slider.Visible = 'off';
            app.ORI3Slider.Value = [0 1];
            app.ORI1_low = uieditfield(app.UIFigure, 'numeric');
            app.ORI1_low.ValueChangedFcn = createCallbackFcn(app, @ORI1_lowValueChanged, true);
            app.ORI2_low = uieditfield(app.UIFigure, 'numeric');
            app.ORI2_low.ValueChangedFcn = createCallbackFcn(app, @ORI2_lowValueChanged, true);
            app.ORI3_low = uieditfield(app.UIFigure, 'numeric');
            app.ORI3_low.ValueChangedFcn = createCallbackFcn(app, @ORI3_lowValueChanged, true);
            app.ORI1_high = uieditfield(app.UIFigure, 'numeric');
            app.ORI1_high.ValueChangedFcn = createCallbackFcn(app, @ORI1_highValueChanged, true);
            app.ORI2_high = uieditfield(app.UIFigure, 'numeric');
            app.ORI2_high.ValueChangedFcn = createCallbackFcn(app, @ORI2_highValueChanged, true);
            app.ORI3_high = uieditfield(app.UIFigure, 'numeric');
            app.ORI3_high.ValueChangedFcn = createCallbackFcn(app, @ORI3_highValueChanged, true);
            app.Base_low = uieditfield(app.UIFigure, 'numeric');
            app.Base_low.ValueChangedFcn = createCallbackFcn(app, @Base_lowValueChanged, true);
            app.Base_low.Visible = 'off';
            app.Overlay_low = uieditfield(app.UIFigure, 'numeric');
            app.Overlay_low.ValueChangedFcn = createCallbackFcn(app, @Overlay_lowValueChanged, true);
            app.Overlay_low.Visible = 'off';
            app.Base_high = uieditfield(app.UIFigure, 'numeric');
            app.Base_high.ValueChangedFcn = createCallbackFcn(app, @Base_highValueChanged, true);
            app.Base_high.Visible = 'off';
            app.Overlay_high = uieditfield(app.UIFigure, 'numeric');
            app.Overlay_high.ValueChangedFcn = createCallbackFcn(app, @Overlay_highValueChanged, true);
            app.Overlay_high.Visible = 'off';
            app.ApplySliceMaskDropDownLabel = uilabel(app.UIFigure);
            app.ApplySliceMaskDropDownLabel.Text = 'Apply Slice Mask';
            app.ApplySliceMaskDropDown = uidropdown(app.UIFigure);
            app.ApplySliceMaskDropDown.Items = {'All'};
            app.ApplySliceMaskDropDown.ValueChangedFcn = createCallbackFcn(app, @ApplySliceMaskDropDownValueChanged, true);
            app.ApplySliceMaskDropDown.Tooltip = {'When loading a base and overlay image, the user can specify to which images the slice masks are applied. '};
            app.ApplySliceMaskDropDown.Value = 'All';
            app.TrimBaseCheckBox = uicheckbox(app.UIFigure);
            app.TrimBaseCheckBox.ValueChangedFcn = createCallbackFcn(app, @TrimBaseCheckBoxValueChanged, true);
            app.TrimBaseCheckBox.Visible = 'off';
            app.TrimBaseCheckBox.Tooltip = {'This will remove the high and low percentile of data values as specified in the Pct Low and Pct High edit fields.'};
            app.TrimBaseCheckBox.Text = 'Trim Base';
            app.TrimOverlayCheckBox = uicheckbox(app.UIFigure);
            app.TrimOverlayCheckBox.ValueChangedFcn = createCallbackFcn(app, @TrimOverlayCheckBoxValueChanged, true);
            app.TrimOverlayCheckBox.Visible = 'off';
            app.TrimOverlayCheckBox.Tooltip = {'This will remove the high and low percentile of data values as specified in the Pct Low and Pct High edit fields.'};
            app.TrimOverlayCheckBox.Text = 'Trim Overlay';
            app.PctLowEditField_Base = uieditfield(app.UIFigure, 'numeric');
            app.PctLowEditField_Base.Limits = [0 100];
            app.PctLowEditField_Base.ValueChangedFcn = createCallbackFcn(app, @PctLowEditField_BaseValueChanged, true);
            app.PctLowEditField_Base.Visible = 'off';
            app.PctLowEditField_Base.Value = 0.01;
            app.PctLowEditField_Overlay = uieditfield(app.UIFigure, 'numeric');
            app.PctLowEditField_Overlay.Limits = [0 100];
            app.PctLowEditField_Overlay.ValueChangedFcn = createCallbackFcn(app, @PctLowEditField_OverlayValueChanged, true);
            app.PctLowEditField_Overlay.Visible = 'off';
            app.PctLowEditField_Overlay.Value = 0.01;
            app.PctHighBase = uilabel(app.UIFigure);
            app.PctHighBase.Visible = 'off';
            app.PctHighBase.Text = 'Pct High';
            app.PctHighEditField_Base = uieditfield(app.UIFigure, 'numeric');
            app.PctHighEditField_Base.Limits = [0 100];
            app.PctHighEditField_Base.ValueChangedFcn = createCallbackFcn(app, @PctHighEditField_BaseValueChanged, true);
            app.PctHighEditField_Base.Visible = 'off';
            app.PctHighEditField_Base.Value = 99.99;
            app.PctHighEditField_Overlay = uieditfield(app.UIFigure, 'numeric');
            app.PctHighEditField_Overlay.Limits = [0 100];
            app.PctHighEditField_Overlay.ValueChangedFcn = createCallbackFcn(app, @PctHighEditField_OverlayValueChanged, true);
            app.PctHighEditField_Overlay.Visible = 'off';
            app.PctHighEditField_Overlay.Value = 99.99;
            app.PctLowOverlay = uilabel(app.UIFigure);
            app.PctLowOverlay.Visible = 'off';
            app.PctLowOverlay.Text = 'Pct Low';
            app.PctLowBase = uilabel(app.UIFigure);
            app.PctLowBase.Visible = 'off';
            app.PctLowBase.Text = 'Pct Low';
            app.PctHighOverlay = uilabel(app.UIFigure);
            app.PctHighOverlay.Visible = 'off';
            app.PctHighOverlay.Text = 'Pct High';
            app.BaseHighVal = uieditfield(app.UIFigure, 'numeric');
            app.BaseHighVal.ValueChangedFcn = createCallbackFcn(app, @BaseHighValValueChanged, true);
            app.BaseHighVal.Visible = 'off';
            app.BaseLowVal = uieditfield(app.UIFigure, 'numeric');
            app.BaseLowVal.ValueChangedFcn = createCallbackFcn(app, @BaseLowValValueChanged, true);
            app.BaseLowVal.Visible = 'off';
            app.OverlayLowVal = uieditfield(app.UIFigure, 'numeric');
            app.OverlayLowVal.ValueChangedFcn = createCallbackFcn(app, @OverlayLowValValueChanged, true);
            app.OverlayLowVal.Visible = 'off';
            app.OverlayHighVal = uieditfield(app.UIFigure, 'numeric');
            app.OverlayHighVal.ValueChangedFcn = createCallbackFcn(app, @OverlayHighValValueChanged, true);
            app.OverlayHighVal.Visible = 'off';
            app.VolumeSliderLabel = uilabel(app.UIFigure);
            app.VolumeSliderLabel.Visible = 'off';
            app.VolumeSliderLabel.Text = 'Volume';
            app.VolumeSlider = uislider(app.UIFigure);
            app.VolumeSlider.Limits = [1 2];
            app.VolumeSlider.MajorTicks = [1 2];
            app.VolumeSlider.ValueChangedFcn = createCallbackFcn(app, @VolumeSliderValueChanged, true);
            app.VolumeSlider.Visible = 'off';
            app.VolumeSlider.Value = 1;
            app.ExportMovieButton = uibutton(app.UIFigure, 'push');
            app.ExportMovieButton.ButtonPushedFcn = createCallbackFcn(app, @ExportMovieButtonPushed, true);
            app.ExportMovieButton.Visible = 'off';
            app.ExportMovieButton.Text = 'Export Movie';
            app.VolumeVal = uieditfield(app.UIFigure, 'numeric');
            app.VolumeVal.ValueChangedFcn = createCallbackFcn(app, @VolumeValValueChanged, true);
            app.VolumeVal.Editable = 'off';
            app.VolumeVal.Visible = 'off';
            app.ResetSlidersButton = uibutton(app.UIFigure, 'push');
            app.ResetSlidersButton.ButtonPushedFcn = createCallbackFcn(app, @ResetSlidersButtonPushed, true);
            app.ResetSlidersButton.Text = 'Reset Sliders';
            app.LoadStateButton = uibutton(app.UIFigure, 'push');
            app.LoadStateButton.ButtonPushedFcn = createCallbackFcn(app, @LoadStateButtonPushed, true);
            app.LoadStateButton.Visible = 'off';
            app.LoadStateButton.Text = 'Load State';
            app.SaveStateButton = uibutton(app.UIFigure, 'push');
            app.SaveStateButton.ButtonPushedFcn = createCallbackFcn(app, @SaveStateButtonPushed, true);
            app.SaveStateButton.Visible = 'off';
            app.SaveStateButton.Text = 'Save State';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = seeVieweR

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end